classdef Main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MainUIFigure                matlab.ui.Figure
        TabGroup                    matlab.ui.container.TabGroup
        SingleTab                   matlab.ui.container.Tab
        OperateoldprojectPanel      matlab.ui.container.Panel
        DeleteoldButton             matlab.ui.control.Button
        RenameoldButton             matlab.ui.control.Button
        LoadoldButton               matlab.ui.control.Button
        NewnameEditField            matlab.ui.control.EditField
        NewnameEditFieldLabel       matlab.ui.control.Label
        SelectprojectDropDown       matlab.ui.control.DropDown
        SelectprojectDropDownLabel  matlab.ui.control.Label
        CreatenewprojectPanel       matlab.ui.container.Panel
        CreatenewButton             matlab.ui.control.Button
        ProjectnameEditField        matlab.ui.control.EditField
        ProjectnameEditFieldLabel   matlab.ui.control.Label
        MultipletestTab             matlab.ui.container.Tab
        CreatenewprojectPanel_2     matlab.ui.container.Panel
        PressandholdtheCrtlkeytoselectmultipleoptionsLabel  matlab.ui.control.Label
        OptionListBox               matlab.ui.control.ListBox
        OptionListBoxLabel          matlab.ui.control.Label
        StartButton                 matlab.ui.control.Button
    end


    methods (Access = private)

        function my_closereq(~,fig)
            close_selection = uiconfirm(fig,'Close the figure window?',...
                'Confirmation');
            switch close_selection
                case 'OK'
                    delete(fig);
                case 'Cancel'
                    return
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath func\
            movegui(app.MainUIFigure,'center');
            app.MainUIFigure.CloseRequestFcn=@(src,event)my_closereq(app,src);
            fullpath=mfilename("fullpath");
            [Mainpath,~]=fileparts(fullpath);
            single_file_path=fullfile(Mainpath,'project.txt');
            if exist(single_file_path,'file')
                fid=fopen(single_file_path,'r');
                single_name=textscan(fid,"%s");
                single_name=string(single_name{1});
                Items_single=string(zeros(length(single_name),1));
                for i=1:length(single_name)
                    Items_single(i)=single_name(i);
                end
                app.SelectprojectDropDown.Items=Items_single;
                app.OptionListBox.Items=Items_single;
            end
        end

        % Button pushed function: CreatenewButton
        function CreatenewButtonPushed(app, event)
            if isempty(app.ProjectnameEditField.Value)
                errordlg("Project name is empty","Empty name");
                return;
            end
            project_name=app.ProjectnameEditField.Value;
            selection=uiconfirm(app.MainUIFigure,['Create project with name ',project_name,'?'],'Create','Icon','question');
            switch selection
                case 'OK'
                    fullpath=mfilename("fullpath");
                    [Mainpath,~]=fileparts(fullpath);
                    file_path=fullfile(Mainpath,'project.txt');
                    if ~exist(file_path,'file')
                        fid=fopen(file_path,'wt');
                        fprintf(fid,"%s",project_name);
                        fclose(fid);
                    else
                        fid=fopen(file_path,'r');
                        old_name=textscan(fid,"%s");
                        old_name=string(old_name{1});
                        if ~isempty(find(strcmp(old_name,project_name), 1))
                            errordlg("Project already exist.","Project already exist.");
                            fclose(fid);
                            return;
                        end
                        fclose(fid);
                        fid=fopen(file_path,'a');
                        fprintf(fid,"\n%s",project_name);
                        fclose(fid);
                    end
                    mkdir(fullfile(Mainpath,project_name));
                    Shape_analysis(project_name);
                    delete(app);
                case 'Cancel'
                    return
            end
        end

        % Button pushed function: LoadoldButton
        function LoadoldButtonPushed(app, event)
            if isempty(app.SelectprojectDropDown.Value)
                errordlg("Project name is empty","Empty name");
                return;
            end
            project_name=app.SelectprojectDropDown.Value;
            selection=uiconfirm(app.MainUIFigure,['Load project with name ',project_name,'?'],'Load','Icon','question');
            switch selection
                case 'OK'
                    Shape_analysis(project_name);
                    delete(app);
                case 'Cancel'
                    return
            end
        end

        % Button pushed function: RenameoldButton
        function RenameoldButtonPushed(app, event)
            newname=app.NewnameEditField.Value;
            if isempty(app.SelectprojectDropDown.Value)
                errordlg("Select project is empty","Empty project");
                return;
            end
            if isempty(newname)
                errordlg("New name is empty","Empty name");
                return;
            end
            newname=string(newname);
            selection=uiconfirm(app.MainUIFigure,strcat("Rename project with name ",newname,"?"),'Rename','Icon','question');
            switch selection
                case 'OK'
                    fullpath=mfilename("fullpath");
                    [Mainpath,~]=fileparts(fullpath);
                    file_path=fullfile(Mainpath,'project.txt');
                    fid=fopen(file_path,'r');
                    old_name=textscan(fid,"%s");
                    old_name=string(old_name{1});
                    if ~isempty(find(strcmp(old_name,newname), 1))
                        errordlg("Project already exist.","Project already exist.");
                        fclose(fid);
                        return;
                    end
                    fclose(fid);
                    fid=fopen(file_path,'w');
                    idx=find(strcmp(old_name,string(app.SelectprojectDropDown.Value)),1);
                    oldname=old_name(idx);
                    old_name(idx)=newname;
                    frewind(fid);
                    fprintf(fid,"%s",old_name(1));
                    for i=2:length(old_name)
                        fprintf(fid,"\n%s",old_name(i));
                    end
                    fclose(fid);
                    app.SelectprojectDropDown.Items=old_name;
                    app.OptionListBox.Items=old_name;
                    movefile(fullfile(Mainpath,oldname),fullfile(Mainpath,newname));
                    msgbox("Rename successfully");
                case 'Cancel'
                    return
            end
        end

        % Button pushed function: DeleteoldButton
        function DeleteoldButtonPushed(app, event)
            project_name=string(app.SelectprojectDropDown.Value);
            if isempty(app.SelectprojectDropDown.Value)
                errordlg("Select project is empty","Empty project");
                return;
            end
            selection=uiconfirm(app.MainUIFigure,strcat("Delete project with name ",project_name,"?"),'Rename','Icon','question');
            switch selection
                case 'OK'
                    fullpath=mfilename("fullpath");
                    [Mainpath,~]=fileparts(fullpath);
                    file_path=fullfile(Mainpath,'project.txt');
                    fid=fopen(file_path,'r');
                    old_name=textscan(fid,"%s");
                    old_name=string(old_name{1});
                    idx=find(strcmp(old_name,project_name),1);
                    old_name(idx)=[];
                    fclose(fid);
                    
                    if ~isempty(old_name)
                        fid=fopen(file_path,'w');
                        fprintf(fid,"%s",old_name(1));
                        for i=2:length(old_name)
                            fprintf(fid,"\n%s",old_name(i));
                        end
                        fclose(fid);
                    else
                        fclose("all");
                        delete(fullfile(Mainpath,'project.txt'));
                    end
                    app.SelectprojectDropDown.Items=old_name;
                    rmdir(project_name,"s");
                    msgbox("Delete successfully");
                case 'Cancel'
                    return
            end
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            if numel(app.OptionListBox.Value)<2
                errordlg("Select at least two options","Insufficient options");
                return;
            end
            selection=uiconfirm(app.MainUIFigure,'Start?','Create','Icon','question');
            switch selection
                case 'OK'
                    Shape_analysis_mul(app.OptionListBox.Value);
                    delete(app);
                case 'Cancel'
                    return
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.MainUIFigure = uifigure('Visible', 'off');
            app.MainUIFigure.Name = 'Main';
            app.MainUIFigure.Icon = fullfile(pathToMLAPP, 'Icon', 'Logo.png');
            app.TabGroup = uitabgroup(app.MainUIFigure);
            app.SingleTab = uitab(app.TabGroup);
            app.SingleTab.Title = 'Single';
            app.CreatenewprojectPanel = uipanel(app.SingleTab);
            app.CreatenewprojectPanel.Title = 'Create new project';
            app.ProjectnameEditFieldLabel = uilabel(app.CreatenewprojectPanel);
            app.ProjectnameEditFieldLabel.Text = 'Project name';
            app.ProjectnameEditField = uieditfield(app.CreatenewprojectPanel, 'text');
            app.ProjectnameEditField.Placeholder = 'Input here';
            app.CreatenewButton = uibutton(app.CreatenewprojectPanel, 'push');
            app.CreatenewButton.ButtonPushedFcn = createCallbackFcn(app, @CreatenewButtonPushed, true);
            app.CreatenewButton.Text = ' Create new';
            app.OperateoldprojectPanel = uipanel(app.SingleTab);
            app.OperateoldprojectPanel.Title = 'Operate old project';
            app.SelectprojectDropDownLabel = uilabel(app.OperateoldprojectPanel);
            app.SelectprojectDropDownLabel.Text = 'Select project';
            app.SelectprojectDropDown = uidropdown(app.OperateoldprojectPanel);
            app.SelectprojectDropDown.Items = {};
            app.SelectprojectDropDown.Value = {};
            app.NewnameEditFieldLabel = uilabel(app.OperateoldprojectPanel);
            app.NewnameEditFieldLabel.Text = 'New name';
            app.NewnameEditField = uieditfield(app.OperateoldprojectPanel, 'text');
            app.NewnameEditField.Placeholder = 'Input here';
            app.LoadoldButton = uibutton(app.OperateoldprojectPanel, 'push');
            app.LoadoldButton.ButtonPushedFcn = createCallbackFcn(app, @LoadoldButtonPushed, true);
            app.LoadoldButton.Text = 'Load old';
            app.RenameoldButton = uibutton(app.OperateoldprojectPanel, 'push');
            app.RenameoldButton.ButtonPushedFcn = createCallbackFcn(app, @RenameoldButtonPushed, true);
            app.RenameoldButton.Text = 'Rename old';
            app.DeleteoldButton = uibutton(app.OperateoldprojectPanel, 'push');
            app.DeleteoldButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteoldButtonPushed, true);
            app.DeleteoldButton.Text = 'Delete old';
            app.MultipletestTab = uitab(app.TabGroup);
            app.MultipletestTab.Title = 'Multiple(test)';
            app.CreatenewprojectPanel_2 = uipanel(app.MultipletestTab);
            app.CreatenewprojectPanel_2.Title = 'Create new project';
            app.StartButton = uibutton(app.CreatenewprojectPanel_2, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.OptionListBoxLabel = uilabel(app.CreatenewprojectPanel_2);
            app.OptionListBoxLabel.Text = 'Option';
            app.OptionListBox = uilistbox(app.CreatenewprojectPanel_2);
            app.OptionListBox.Items = {};
            app.OptionListBox.Multiselect = 'on';
            app.OptionListBox.Value = {};
            app.PressandholdtheCrtlkeytoselectmultipleoptionsLabel = uilabel(app.CreatenewprojectPanel_2);
            app.PressandholdtheCrtlkeytoselectmultipleoptionsLabel.Enable = 'off';
            app.PressandholdtheCrtlkeytoselectmultipleoptionsLabel.Text = 'Press and hold the ''Crtl'' key to select multiple options';
            % Show the figure after all components are created
            app.MainUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MainUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MainUIFigure)
        end
    end
end