classdef Shape_analysis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Shape_analysisUIFigure          matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        ImportMenu                      matlab.ui.container.Menu
        ReturnMainMenu                  matlab.ui.container.Menu
        ExitMenu                        matlab.ui.container.Menu
        PreprocessMenu                  matlab.ui.container.Menu
        WriteVideoMenu                  matlab.ui.container.Menu
        KeypointdetectMenu              matlab.ui.container.Menu
        ConvertmeshMenu                 matlab.ui.container.Menu
        EvaluateMenu                    matlab.ui.container.Menu
        AddBCSgroundtruthMenu           matlab.ui.container.Menu
        EvaluateaccuracyMenu            matlab.ui.container.Menu
        ModelMenu                       matlab.ui.container.Menu
        TrainMenu                       matlab.ui.container.Menu
        ScoreMenu                       matlab.ui.container.Menu
        SingleMenu                      matlab.ui.container.Menu
        MultiplyMenu                    matlab.ui.container.Menu
        InfoOutTextArea                 matlab.ui.control.TextArea
        TabGroup                        matlab.ui.container.TabGroup
        WritevideoTab                   matlab.ui.container.Tab
        PanelConvertdepthimagetograyandsyntheticavideowithextensionavi  matlab.ui.container.Panel
        CheckwhethertowritegrayimagesLabel  matlab.ui.control.Label
        WritegrayimageCheckBox          matlab.ui.control.CheckBox
        WriteaviButton                  matlab.ui.control.Button
        DetectionTab                    matlab.ui.container.Tab
        Panel2KeypointdetectioninDLC    matlab.ui.container.Panel
        OpentheGUIofDeepLabCutDLCanddetectkeypointsonthatinterfaceLabel  matlab.ui.control.Label
        OpenDLCButton                   matlab.ui.control.Button
        ConverttomeshTab                matlab.ui.container.Tab
        Panel3Converttomeshwithsametopologyprogress  matlab.ui.container.Panel
        AddNonrigidtransformCheckBox    matlab.ui.control.CheckBox
        WriteoriginalpointcloudCheckBox  matlab.ui.control.CheckBox
        SmoothlevelSpinner              matlab.ui.control.Spinner
        SmoothlevelLabel                matlab.ui.control.Label
        DownsamplelevelSpinner          matlab.ui.control.Spinner
        DownsamplelevelSpinnerLabel     matlab.ui.control.Label
        IndicatorlightLamp              matlab.ui.control.Lamp
        IndicatorlightLampLabel         matlab.ui.control.Label
        TrimmingmethodButtonGroup       matlab.ui.container.ButtonGroup
        NonconvexButton                 matlab.ui.control.RadioButton
        ConvexButton                    matlab.ui.control.RadioButton
        SetparameterstoconvertdepthimagestomeshLabel  matlab.ui.control.Label
        StartconvertButton              matlab.ui.control.Button
        fxTextArea                      matlab.ui.control.TextArea
        fxTextAreaLabel                 matlab.ui.control.Label
        cyTextArea                      matlab.ui.control.TextArea
        cyTextAreaLabel                 matlab.ui.control.Label
        cxTextArea                      matlab.ui.control.TextArea
        cxTextAreaLabel                 matlab.ui.control.Label
        fyTextArea                      matlab.ui.control.TextArea
        fyTextAreaLabel                 matlab.ui.control.Label
        CalibrationparametersLabel      matlab.ui.control.Label
        InputtruthTab                   matlab.ui.container.Tab
        Panel4Inputgroundtruthvalue     matlab.ui.container.Panel
        SaveButton                      matlab.ui.control.Button
        ImportButton                    matlab.ui.control.Button
        LoadButton                      matlab.ui.control.Button
        EnterthegroundtruthinthesecondcolumnLabel  matlab.ui.control.Label
        UITable                         matlab.ui.control.Table
        EvaluateTab                     matlab.ui.container.Tab
        TextArea                        matlab.ui.control.TextArea
        UITable2                        matlab.ui.control.Table
        Panel5Evaluateaccuracy          matlab.ui.container.Panel
        Diff_cal_spaceDropDown          matlab.ui.control.DropDown
        Diff_cal_spaceDropDownLabel     matlab.ui.control.Label
        UpperlimitofvalueEditField      matlab.ui.control.NumericEditField
        UpperlimitofvalueEditFieldLabel  matlab.ui.control.Label
        LowerlimitofvalueEditField      matlab.ui.control.NumericEditField
        LowerlimitofvalueEditFieldLabel  matlab.ui.control.Label
        DisplayincrementEditField       matlab.ui.control.NumericEditField
        DisplayincrementEditFieldLabel  matlab.ui.control.Label
        DiscretizationincrementEditField  matlab.ui.control.NumericEditField
        DiscretizationincrementEditFieldLabel  matlab.ui.control.Label
        TesttimesSpinner                matlab.ui.control.Spinner
        TesttimesSpinner_2Label         matlab.ui.control.Label
        IndicatorlightLamp_2            matlab.ui.control.Lamp
        IndicatorlightLamp_2Label       matlab.ui.control.Label
        StartevaluateButton             matlab.ui.control.Button
        TrainTab                        matlab.ui.container.Tab
        Panel6Trainamodel               matlab.ui.container.Panel
        Difference_calculate_spaceDropDown  matlab.ui.control.DropDown
        Difference_calculate_spaceDropDownLabel  matlab.ui.control.Label
        WritethealignedmeshesCheckBox   matlab.ui.control.CheckBox
        TrainaaverageshapeandrecordtheminandmaxscoreLabel  matlab.ui.control.Label
        TrainButton                     matlab.ui.control.Button
        ScoreTab                        matlab.ui.container.Tab
        Panel7Scoretheanimals           matlab.ui.container.Panel
        BrowseButton                    matlab.ui.control.Button
        DLCpath                         matlab.ui.control.EditField
        ExportButton                    matlab.ui.control.Button
        UITable3                        matlab.ui.control.Table
        FileordirectoryButtonGroup      matlab.ui.container.ButtonGroup
        MultipleButton                  matlab.ui.control.RadioButton
        SingleButton                    matlab.ui.control.RadioButton
        ScoreButton                     matlab.ui.control.Button
        EditparaButton                  matlab.ui.control.Button
    end


    properties (Access = private)
        cellArrayText;
        project_name; 
    end

    methods (Access = private)
        %Output information to textarea
        function Output_Info(app,str)
            string=sprintf('%s\n',str);
            app.cellArrayText=vertcat(app.InfoOutTextArea.Value,string);
            app.InfoOutTextArea.Value=app.cellArrayText;
            scroll(app.InfoOutTextArea,"bottom");
            pause(1e-6);
        end

        function Add_BCS_Truth(app)
            selection=uiconfirm(app.Shape_analysisUIFigure ,'Import file name and reset BCS?', ...
                'Confirm','Icon','warning');
            switch selection
                case 'OK'
                    fullpath=mfilename("fullpath");
                    [Mainpath,~]=fileparts(fullpath);
                    Mainpath=string(Mainpath);
                    BCS=readtable(strcat(Mainpath,"\",app.project_name,"\BCS.dat"));
                    msgbox('Enter the true value of BCS in the second column of the table');
                    app.UITable.Data=BCS;
                case 'Cancel'
                    return
            end
        end

        function Evaluate_Accuracy(app)
            %% Evaluate accuracy
            % Pre-processing data(Train)
            fullpath=mfilename("fullpath");
            [Mainpath,~]=fileparts(fullpath);
            Mainpath=string(Mainpath);
            if ~exist(strcat(Mainpath,"\",app.project_name,"\Preprocessed\mesh\"),'file')||...
                    isempty(dir(strcat(Mainpath,"\",app.project_name,"\Preprocessed\mesh\*.ply")))
                errordlg('Mesh data does not exist, please reconstruct the mesh firstly','Not file error');
                return;
            end

            % Indicator light turns green
            app.IndicatorlightLamp_2.Color=[0,1,0];

            path=strcat(Mainpath,"\",app.project_name,"\Preprocessed\mesh\");
            file=dir(strcat(path,"*.ply"));
            N=length(file);
            file_idx=1:N;
            train_num=round(N*0.8); % The size of training set
            test_num=N-train_num; % The size of test set
            str=sprintf('Training Set Size: %d\nTest Set Size: %d',train_num,test_num);
            app.TextArea.Value=str;
            pause(1e-6);
            
            % Read the BCS
            BCS_file=readtable(strcat(Mainpath,"\",app.project_name,"\BCS.dat"));
            BCS_fn=string(BCS_file{:,1});
            BCS=table2array(BCS_file(:,2));
            BCS_train_idx=zeros(train_num,1);

            times_fold=app.TesttimesSpinner.Value;
            acc=zeros(times_fold,1);
            app_acc=zeros(times_fold,4);
            Bar=waitbar(0,'Evaluating accuracy');
            for r=1:times_fold
                % Determine if the user has interrupted the program
                if ~isvalid(Bar) 
                    app.IndicatorlightLamp_2.Color=[1,0,0];
                    errordlg('User stop','User Stop');
                    return;
                end

                waitbar(r/times_fold,Bar,'Evaluating accuracy'); % Update progress bar

                % Obtain the training set randomly from whole set
                train_idx=sort(file_idx(randperm(numel(file_idx),train_num)));

                % Obtain the BCS corresponding to the training set
                for i=1:train_num
                    train_name=split(file(train_idx(i)).name,["_","."]);
                    train_name=string(str2double(train_name(2)));
                    BCS_train_idx(i)=find(strcmp(train_name,BCS_fn)==1);
                end
                BCS_train=BCS(BCS_train_idx);

                %Set the remaining dataset as the test set
                test_idx=setdiff(file_idx,train_idx);

                train_mesh=readSurfaceMesh(strcat(path,string(file(train_idx(1)).name))); %average shape variable
                V_0=train_mesh.Vertices;
                T_0=train_mesh.Faces;
                V_0=V_0-mean(V_0);%Translate the target point's gravity to the origin
                V_0=V_0/sqrt(sum(sum(V_0.^2)));%normalization

                Vnum=size(V_0,1);
                V=cell(train_num,1);

                %Translate the source points' center of gravity to the origin and normalization it
                for i=1:train_num
                    V{i}=readSurfaceMesh(strcat(path,string(file(train_idx(i)).name))).Vertices;
                    V{i}=V{i}-mean(V{i});
                    V{i}=V{i}/sqrt(sum(sum(V{i}.^2)));
                end

                % Alignment
                iter=0;
                while iter<10
                    iter=iter+1;

                    % Align all shapes to the average shape
                    for i=1:train_num
                        V_i=V{i};
                        [~,TV_i,~]=procrustes(V_0,V_i);
                        V{i}=TV_i;
                    end

                    V_0_temp=generate_avg_shape(V,train_num,Vnum);
                    [~,V_0_temp,~]=procrustes(V_0,V_0_temp);
                    V_0=V_0_temp;
                end

                % Align the boundaries after the shape is basically aligned
                tri_0=triangulation(double(T_0),double(V_0));
                b_0=tri_0.freeBoundary;
                b_0=b_0(:,1);
                p_0=pointCloud(V_0(b_0,:));
                for i=1:train_num
                    tri_i=triangulation(double(T_0),double(V{i}));
                    b_i=tri_i.freeBoundary;
                    b_i=b_i(:,1);
                    p_i=pointCloud(V{i}(b_i,:));
                    t=pcregistericp(p_i,p_0);
                    V{i}=V{i}*t.R+t.Translation;
                end

                if strcmp(app.Diff_cal_spaceDropDown.Value,'Basis vector')
                    %% Obtain the basis vector of the shape
                    Stacked_V=zeros(size(V{1},1)*size(V{1},2),length(V));
                    for i=1:length(V)
                        Stacked_V(:,i)=reshape(V{i}',[],1);
                    end
                    Stacked_V=Stacked_V';
                    centered_X = Stacked_V - mean(Stacked_V);
                    covariance = cov(centered_X);
                    [Vector, D] = eig(covariance);
                    [sorted_eigenvalues, index] = sort(diag(D), 'descend');
                    total_variance = sum(sorted_eigenvalues);
                    target_variance = 0.99 * total_variance;
                    explained_variance = 0;
                    i = 1;
                    while explained_variance < target_variance
                        explained_variance = explained_variance + sorted_eigenvalues(i);
                        i = i + 1;
                    end
                    basis_vectors = Vector(:, index(1:i-1));
                else
                    %% Adjust the position of the livestocks' back
                    mu=mean(V_0);
                    rotate_mat=pca(V_0-mu); % Find the main direction of the point set
                    rotate_x=[1 0 0;0 -1 0;0 0 -1]; % Flip it 180 degrees along the x-axis.
                    V_0=(V_0-mu)*rotate_mat*rotate_x;
                    for i=1:train_num
                        V{i}=(V{i}-mu)*rotate_mat*rotate_x;
                    end
                end
                %%
                min_BCS=min(BCS_train);
                max_BCS=max(BCS_train);
                V_model=V_0;
                if strcmp(app.Diff_cal_spaceDropDown.Value,'Basis vector')
                    V_model_in_basis=basis_vectors\reshape(V_model',[],1);
                end

                % Calculate scores of animals in training sets
                train_score=zeros(train_num,1);
                for i=1:train_num
                    V_S=V{i};
                    if strcmp(app.Diff_cal_spaceDropDown.Value,'Basis vector')
                        V_S_in_basis=basis_vectors\reshape(V_S',[],1);
                        train_score(i)=evaluate_score(V_S_in_basis,V_model_in_basis,T_0,'basis_vector');
                    else
                        train_score(i)=evaluate_score(V_S,V_model,T_0,'euclidean');
                    end
                end
                min_score=min(train_score);
                max_score=max(train_score);


                %% Evaluate scores of all animals(Test)
                % Obtain the BCS corresponding to the test set
                BCS_test_idx=zeros(test_num,1);
                for i=1:test_num
                    test_name=split(file(test_idx(i)).name,["_","."]);
                    test_name=string(str2double(test_name(2)));
                    BCS_test_idx(i)=find(strcmp(test_name,BCS_fn)==1);
                end
                BCS_test=BCS(BCS_test_idx);

                meshname=strings(length(test_num),1);
                for n=1:test_num
                    temp=split(file(test_idx(n)).name,["_","."]);
                    meshname(n)=temp(2);
                end
                meshname=str2double(meshname);

                V_T=V_model;
                Faces=T_0;
                test_score=zeros(test_num,1);

                % Calculate scores of all livestocks
                for i=1:test_num
                    V_S=readSurfaceMesh(strcat(path,string(file(test_idx(i)).name))).Vertices;
                    V_S=V_S-mean(V_S);
                    V_S=V_S/sqrt(sum(sum(V_S.^2)));

                    [~,V_S,~]=procrustes(V_T,V_S);
                    if strcmp(app.Diff_cal_spaceDropDown.Value,'Basis vector')
                        V_S_in_basis=basis_vectors\reshape(V_S',[],1);
                        test_score(i)=evaluate_score(V_S_in_basis,V_model_in_basis,Faces,'basis_vector');
                    else
                        test_score(i)=evaluate_score(V_S,V_T,Faces,'euclidean');
                    end
                end

                % Standardized BCS
                up=app.UpperlimitofvalueEditField.Value;
                low=app.LowerlimitofvalueEditField.Value;
                if up<low
                    errordlg("The lower limit must be less than the upper",'limit error');
                    return;
                end
                if app.DiscretizationincrementEditField.Value~=0
                    enum=low:app.DiscretizationincrementEditField.Value:up;
                end
                norm_score=(test_score-min_score)/(max_score-min_score)...
                    *(max_BCS-min_BCS)+min_BCS;

                %% calculate the accuracy
                acc_num=0;
                approximate_num=zeros(1,4);
                file_name=split(BCS_fn,["_","."]);
                file_name=str2double(file_name);
                idx=zeros(test_num,1);
                for n=1:test_num
                    idx(n)=find(file_name==meshname(n));
                end
                
                truth_test=zeros(test_num,1);
                for i=1:test_num
                    % Discretizing BCS
                    if app.DiscretizationincrementEditField.Value~=0
                        neigh=abs(enum-norm_score(i));
                        order=find(neigh==min(neigh));
                        norm_score(i)=enum(order);
                    end

                    name=split(file(test_idx(i)).name,["_","."]);
                    name=name{2};
                    j=find(file_name(idx)==str2double(name));
                    
                    truth_test(i)=BCS_test(j);
                    if abs(norm_score(i)-BCS_test(j))<=0
                        acc_num=acc_num+1;
                    end
                    
                    dis_incre=app.DisplayincrementEditField.Value;
                    for k=1:4
                        if abs(norm_score(i)-BCS_test(j))<=(dis_incre*k)
                            approximate_num(k)=approximate_num(k)+1;
                        end
                    end
                end
                acc(r)=acc_num/test_num;
                app_acc(r,:)=approximate_num/test_num;
            end
            Avg=[mean(acc)*100;mean(app_acc(:,1))*100;mean(app_acc(:,2))*100;...
                mean(app_acc(:,3))*100;mean(app_acc(:,4))*100];
            Min=[min(acc)*100;min(app_acc(:,1))*100;min(app_acc(:,2))*100;...
                min(app_acc(:,3))*100;min(app_acc(:,4))*100];
            Max=[max(acc)*100;max(app_acc(:,1))*100;max(app_acc(:,2))*100;...
                max(app_acc(:,3))*100;max(app_acc(:,4))*100];
            diff1=sprintf("Diff==%f",dis_incre*0);
            diff2=sprintf("Diff<=%f",dis_incre*1);
            diff3=sprintf("Diff<=%f",dis_incre*2);
            diff4=sprintf("Diff<=%f",dis_incre*3);
            diff5=sprintf("Diff<=%f",dis_incre*4);
            Differ=[diff1;diff2;diff3;diff4;diff5];
            close(Bar);
            Table=table(Differ,Avg,Min,Max);
            app.UITable2.Data=Table;
            app.IndicatorlightLamp_2.Color=[1,0,0];
        end

        function Write_Video(app)
            fullpath=mfilename("fullpath");
            [Mainpath,~]=fileparts(fullpath);
            Mainpath=string(Mainpath);
            if ~exist(strcat(Mainpath,"\",app.project_name,"\Preprocessed\depth\"),'file')
                errordlg('The depth image has not been imported. Please import it first');
                return;
            end
            if isempty(dir(strcat(Mainpath,"\",app.project_name,"\Preprocessed\depth\*.png")))
                warndlg('No depth image data exists','Data not exists');
            end
            Output_Info(app,'Start conversion...');
            if ~exist(strcat(Mainpath,"\",app.project_name,"\Preprocessed\gray\"),'file')
                mkdir(strcat(Mainpath,"\",app.project_name,"\Preprocessed\gray\"));
            end
            delete(strcat(Mainpath,"\",app.project_name,"\Preprocessed\gray\*"));
            depthpath=(strcat(Mainpath,"\",app.project_name,"\Preprocessed\depth\"));
            graypath=(strcat(Mainpath,"\",app.project_name,"\Preprocessed\gray\"));

            file=dir(strcat(depthpath,"*.png"));
            vid=VideoWriter(strcat(graypath,"BCS.avi"));
            open(vid);
            Bar=waitbar(0,'Converting');
            for i=1:length(file)
                waitbar(i/length(file),Bar,'Converting');
                % read the Depth image
                Depth=double(imread(fullfile(depthpath,file(i).name)));

                maxD=max(max(Depth));
                minD=min(min(Depth));

                % Convert to gray values
                gray_double=(Depth-minD)/(maxD-minD)*255;
                gray_int=uint8(gray_double);

                gray_name=split(file(i).name,'_');
                gray_name=gray_name{2};
                if app.WritegrayimageCheckBox.Value
                    imwrite(gray_int,strcat(graypath,"gray_",string(gray_name)));
                end
                writeVideo(vid,gray_int);
            end
            close(vid);
            close(Bar);
            Output_Info(app,'Conversion completed.')
        end

        function V_r = Rotate_point(~,V,theta,rotate_axis)
            x=V(:,1);
            y=V(:,2);
            z=V(:,3);

            vx=rotate_axis(1);
            vy=rotate_axis(2);
            vz=rotate_axis(3);

            r=theta*pi/180;
            sinr=sin(r);
            cosr=cos(r);

            r_x=(vx*vx*(1-cosr)+cosr)*x+(vx*vy*(1-cosr)-vz*sinr)*y+(vx*vz*(1-cosr)+vy*sinr)*z;
            r_y=(vx*vy*(1-cosr)+vz*sinr)*x+(vy*vy*(1-cosr)+cosr)*y+(vy*vz*(1-cosr)-vx*sinr)*z;
            r_z=(vx*vz*(1-cosr)-vy*sinr)*x+(vy*vz*(1-cosr)+vx*sinr)*y+(vz*vz*(1-cosr)+cosr)*z;
            V_r=[r_x,r_y,r_z];
        end


        function Train_model(app)
            %% Register all mapped meshes, Training to obtain a model
            %Refer: active shape model(ASM), Statistical Shape Models(SSM)

            %% Pre-processing data
            Output_Info(app,'Reading file...');
            fullpath=mfilename("fullpath");
            Mainpath=fileparts(fullpath);
            Mainpath=string(Mainpath);
            path=strcat(Mainpath,"\",app.project_name,"\Preprocessed\mesh\");
            file=dir(strcat(path,'*.ply'));
            
            BCS_file=readtable(strcat(Mainpath,"\",app.project_name,"\BCS.dat"));
            BCS_fn=string(BCS_file{:,1});
            BCS=table2array(BCS_file(:,2));
            BCS_idx=zeros(length(file),1);

            for i=1:length(file)
                train_name=split(file(i).name,["_","."]);
                train_name=train_name{2};
                BCS_idx(i)=find(strcmp(string(str2double(train_name)),BCS_fn)==1);
            end
            BCS=BCS(BCS_idx);

            Smesh=readSurfaceMesh(strcat(path,string(file(1).name))); %average shape variable
            V_0=Smesh.Vertices;
            T_0=Smesh.Faces;
            V_0=V_0-mean(V_0);%Translate the target point's center of gravity to the origin
            V_0=V_0/sqrt(sum(sum(V_0.^2)));%normalization

            Vnum=size(V_0,1);
            fnum=length(file);
            V=cell(fnum,1);
            %Translate the source points' center of gravity to the origin and
            %normalization it
            for i=1:fnum
                V{i}=readSurfaceMesh(strcat(path,string(file(i).name))).Vertices;
                V{i}=V{i}-mean(V{i});
                V{i}=V{i}/sqrt(sum(sum(V{i}.^2)));
            end

            %% alignment
            Output_Info(app,'Start alignment iterately.');
            iter=0;
            while iter<10
                iter=iter+1;
                %Align all shapes to the average shape
                for i=1:fnum
                    V_i=V{i};
                    [~,V{i},~]=procrustes(V_0,V_i);
                end

                V_0_temp=generate_avg_shape(V,fnum,Vnum);
                [~,V_0_temp,~]=procrustes(V_0,V_0_temp);
                loss=mean(sqrt(sum((V_0_temp-V_0).^2)));
                V_0=V_0_temp/sqrt(sum(sum(V_0_temp.^2)));
                Output_Info(app,['Current iterations is : ',num2str(iter),', loss is: ',num2str(loss)]);
            end

            % Align the boundaries after the shape is basically aligned
            tri_0=triangulation(double(T_0),double(V_0));
            b_0=tri_0.freeBoundary;
            b_0=b_0(:,1);
            for i=1:fnum
                tri_i=triangulation(double(T_0),double(V{i}));
                b_i=tri_i.freeBoundary;
                b_i=b_i(:,1);
                % Find the nearest boundary point index
                near_idx=zeros(length(b_0),1);
                for j=1:length(b_0)
                    near_idx(j)=dsearchn(b_i,b_0(j));
                end
                b_i=b_i(near_idx);
                [~,~,t]=procrustes(b_0,b_i);
                V{i}=t.b*V{i}*t.T+t.c(1);
            end

            %% Write model
            Output_Info(app,'Writing model');
            min_BCS=min(BCS);
            max_BCS=max(BCS);
            V_T=V_0;
            
            if strcmp(app.Difference_calculate_spaceDropDown.Value,'Basis vector')
                %% Obtain the basis vector of the shape
                Stacked_V=zeros(size(V{1},1)*size(V{1},2),length(V));
                for i=1:length(V)
                    Stacked_V(:,i)=reshape(V{i}',[],1);
                end
                Stacked_V=Stacked_V';
                centered_X = Stacked_V- mean(Stacked_V);
                covariance = cov(centered_X);
                [Vector, D] = eig(covariance);
                [sorted_eigenvalues, index] = sort(diag(D), 'descend');
                total_variance = sum(sorted_eigenvalues);
                target_variance = 0.99 * total_variance;
                explained_variance = 0;
                i = 1;
                while explained_variance < target_variance
                    explained_variance = explained_variance + sorted_eigenvalues(i);
                    i = i + 1;
                end
                basis_vectors = Vector(:, index(1:i-1));
                V_T_in_basic=basis_vectors\reshape(V_T',[],1);
            else
                %% Adjust the position of  back
                mu=mean(V_0);
                rotate_mat=pca(V_0-mu);
                rotate_x=[1 0 0;0 -1 0;0 0 -1]; % Flip it 180 degrees along the x-axis.
                V_0=(V_0-mu)*rotate_mat*rotate_x;

                for i=1:fnum
                    V{i}=(V{i}-mu)*rotate_mat*rotate_x;
                end
            end

            % Calculate scores of all animals
            score=zeros(length(file),1);
            for i=1:length(file)
                V_S=V{i};
                if strcmp(app.Difference_calculate_spaceDropDown.Value,'Basis vector')
                    V_S_in_basic=basis_vectors\reshape(V_S',[],1);
                    score(i)=evaluate_score(V_S_in_basic,V_T_in_basic,T_0,'basis_vector');
                else
                    score(i)=evaluate_score(V_S,V_T,T_0,'euclidean');
                end
            end

            % Write the mesh after align
            if app.WritethealignedmeshesCheckBox.Value
                if exist(strcat(Mainpath,"\",app.project_name,"\Preprocessed\alignmesh"),'file')
                    rmdir(strcat(Mainpath,"\",app.project_name,"\Preprocessed\alignmesh"),"s");
                end
                for i=1:length(file)
                    if ~exist(strcat(Mainpath,"\",app.project_name,"\Preprocessed\alignmesh"),'file')
                        mkdir(strcat(Mainpath,"\",app.project_name,"\Preprocessed\alignmesh"));
                    end
                    align_mesh=surfaceMesh(V{i},T_0);
                    writeSurfaceMesh(align_mesh,strcat(Mainpath,"\",app.project_name,"\Preprocessed\alignmesh\",string(file(i).name)));
                end
            end

            min_score=min(score);
            max_score=max(score);
            model_mesh=surfaceMesh(V_0,T_0);
            writeSurfaceMesh(model_mesh,strcat(Mainpath,"\",app.project_name,"\model.ply"));
            if strcmp(app.Difference_calculate_spaceDropDown.Value,'Basis vector')
                save(strcat(Mainpath,"\",app.project_name,"\model.mat"),'min_BCS','max_BCS','min_score','max_score','basis_vectors');
            else
save(strcat(Mainpath,"\",app.project_name,"\model.mat"),'min_BCS','max_BCS','min_score','max_score');
            end
            Output_Info(app,'Training completed');
        end

        function Score(app)
            %% Read the file include depth image
            if strcmp(app.FileordirectoryButtonGroup.SelectedObject.Text,'Single')
                [file,depthpath]=uigetfile('*.png','Select a depth image with extension of png');
                if isequal(file,0)
                    Output_Info(app,'User selected Cancel');
                    return;
                end
            else
                depthpath=uigetdir('C:/',['Select a diretory that include ' ...
                    'depth image with extension of png']);
                if isequal(depthpath,0)
                    Output_Info(app,'User selected Cancel');
                    return;
                end
                file=dir([depthpath,'\*.png']);
                if isempty(file)
                    errordlg('Not depth image exist in this path.','No depth image exist');
                    return;
                end
            end

            Output_Info(app,'Starting calculating...');
            % Get the current file path
            fullpath=mfilename("fullpath");
            Mainpath=fileparts(fullpath);
            Mainpath=string(Mainpath);
            if isempty(app.DLCpath.Value)
                errordlg("Please browse the diretory of DLC firstly.","DLC path empty");
                return;
            end

            %% Read model parameters and load model shape
            load(strcat(Mainpath,"\",app.project_name,"\model.mat"));
            Smesh=readSurfaceMesh(strcat(Mainpath,"\",app.project_name,"\model.ply"));
            V_T=Smesh.Vertices;
            Faces=Smesh.Faces;


            %% Initialize variable
            if strcmp(app.FileordirectoryButtonGroup.SelectedObject.Text,'Single')
                score=zeros(1,1);
                fnum=1;
                filename=file;
            else
                score=zeros(length(file),1);
                fnum=length(file);
                filename=string(zeros(fnum,1));
            end


            %% Convert depth image to gray image, then compose to a video
            Output_Info(app,'Composing video...');
            vid=VideoWriter(fullfile(depthpath,'BCS.avi'));
            open(vid);
            for i=1:fnum
                % read the Depth image
                if strcmp(app.FileordirectoryButtonGroup.SelectedObject.Text,'Single')
                    Depth=double(imread(fullfile(depthpath,filename)));
                else
                    Depth=double(imread(fullfile(depthpath,file(i).name)));
                end
                maxD=max(max(Depth));
                minD=min(min(Depth));

                % Convert to gray values
                gray_double=(Depth-minD)/(maxD-minD)*255;
                gray_int=uint8(gray_double);
                writeVideo(vid,gray_int);
            end
            close(vid);


            %% Using DLC to analysis video to get label points
            % Clear old files
            delete(fullfile(depthpath,"*.csv"));
            delete(fullfile(depthpath,"*.h5"));
            delete(fullfile(depthpath,"*.pickle"));
            delete([fullfile(depthpath,'mesh'),'\*']);

            videofile_path=fullfile(depthpath,'BCS.avi');
            Output_Info(app,'Opening DLC...');
            fid2=fopen(strcat(Mainpath,"\",app.project_name,"\DLC_analyze.py"),'w');
            code1="import deeplabcut";
            code2=strcat("config_path=r""",string(app.DLCpath.Value),"\config.yaml""");
            code3=strcat("videofile_path=r""",videofile_path,"""");
            code4="deeplabcut.analyze_videos(config_path,[videofile_path],save_as_csv=True)";
            fprintf(fid2,"%s\n%s\n%s\n%s",code1,code2,code3,code4);
            pycommand=strcat("conda activate DEEPLABCUT&&python """,Mainpath,"\",app.project_name,"\DLC_analyze.py""");
            status=system(pycommand);
            if status
                errordlg('DLC startup failed, the environment may not have been installed yet');
                return;
            end


            %% Read DLC analysis results
            csvfile=dir(fullfile(depthpath,'*.csv'));
            marktable=readtable(fullfile(depthpath,csvfile.name));
            markpoint=table2array(marktable);
            marknum=floor(size(marktable,2)/3);
            likelihood=zeros(fnum,marknum);
            for i=0:marknum
                if i~=marknum
                    likelihood(:,marknum-i)=markpoint(:,(marknum-i)*3+1);
                end
                markpoint(:,(marknum-i)*3+1)=[];
            end


            %% Initialize calibration parameters of camera
            fx=str2double(app.fxTextArea.Value);
            fy=str2double(app.fyTextArea.Value);
            cx=str2double(app.cxTextArea.Value);
            cy=str2double(app.cyTextArea.Value);

            intrinsic=[fx,0,cx
                0,fy,cy
                0,0,1];

            fdx=intrinsic(1,1);
            fdy=intrinsic(2,2);
            u0=intrinsic(1,3);
            v0=intrinsic(2,3);


            %% Read the key point index of the model
            model=load(strcat(Mainpath,"\",app.project_name,"\model_key_idx.mat"));
            model_key_idx=model.model_key_idx;
            if length(model_key_idx)~=marknum
                errordlg('Inconsistent number of key points','keypoint number error');
                return;
            end


            %% Initialize model mapping parameters
            V_tar=V_T;
            T_tar=double(Faces);
            tri=triangulation(T_tar,V_tar);
            b_tar=tri.freeBoundary();
            b_tar=b_tar(:,1);

            % Using the position of the first keypoint as the starting index
            nearest_k1_idx=dsearchn(V_tar(b_tar,:),V_tar(model_key_idx(1),:));
            b_tar=[b_tar(nearest_k1_idx:end);b_tar(1:nearest_k1_idx-1)];

            % search the corners around the egde
            if marknum==3
                nearest_V1_idx=dsearchn(V_tar(b_tar,:),V_tar(model_key_idx(1),:));
                nearest_V2_idx=dsearchn(V_tar(b_tar,:),V_tar(model_key_idx(2),:));
                nearest_V3_idx=dsearchn(V_tar(b_tar,:),V_tar(model_key_idx(3),:));
                sel_cones_tar=[nearest_V1_idx,nearest_V2_idx,nearest_V3_idx];
            else % If the key points are greater than or equal to 4, select 4 cone points
                interval=marknum/4;
                nearest_V1_idx=dsearchn(V_tar(b_tar,:),V_tar(model_key_idx(round(interval*1)),:));
                nearest_V2_idx=dsearchn(V_tar(b_tar,:),V_tar(model_key_idx(round(interval*2)),:));
                nearest_V3_idx=dsearchn(V_tar(b_tar,:),V_tar(model_key_idx(round(interval*3)),:));
                nearest_V4_idx=dsearchn(V_tar(b_tar,:),V_tar(model_key_idx(round(interval*4)),:));
                sel_cones_tar=[nearest_V1_idx,nearest_V2_idx,nearest_V3_idx,nearest_V4_idx];
            end
            sel_cones_tar=sort(sel_cones_tar);

            %% Process each image
            Cannotmap=0;
            Bar=waitbar(0,'Converting');
            Output_Info(app,"Start convert to mesh");
            for n=1:fnum
                if ~isvalid(Bar)
                    Output_Info(app,'Stop convert');
                    errordlg('User stop','User Stop');
                    return;
                end
                waitbar(n/fnum,Bar,'Converting');

                % Extract file names without extensions
                if strcmp(app.FileordirectoryButtonGroup.SelectedObject.Text,'Single')
                    dataname=split(filename,".");
                    dataname=dataname{1};
                    D=imread(fullfile(depthpath,filename));
                else
                    dataname=split(file(n).name,".");
                    dataname=dataname{1};
                    D=imread(fullfile(depthpath,file(n).name));
                end
                

                % Extract the coordinates of key points in the current depth image
                corner_coor=markpoint(n,:);
                keypointnum=length(corner_coor)/2;
                keypoint=zeros(keypointnum,2);

                % Determine if there is depth at key points
                isdepthmissing=0;
                for i=1:keypointnum
                    keypoint(i,:)=round(corner_coor([(i-1)*2+1,(i-1)*2+2]));
                    if D(keypoint(i,2),keypoint(i,1))==0
                        if strcmp(app.FileordirectoryButtonGroup.SelectedObject.Text,'Single')
                            Output_Info(app,['The depth of the key point of file ',filename,' is zeros ',...
                                'and cannot be converted to mesh.']);
                        else
                            Output_Info(app,['The depth of the key point of file ',file(n).name,' is zeros ',...
                                'and cannot be converted to mesh.']);
                        end
                        Cannotmap=Cannotmap+1;
                        isdepthmissing=1;
                        break;
                    end
                end
                if isdepthmissing
                    continue;
                end

                % Determine whether key points are accurately predicted
                isKeypointDeviation=0;
                for i=1:keypointnum
                    if likelihood(n,i)<0.95
                        Output_Info(app,['file ',dataname,' key point position deviation']);
                        Cannotmap=Cannotmap+1;
                        isKeypointDeviation=1;
                        break;
                    end
                end
                if isKeypointDeviation
                    continue;
                end

                row=size(D,1);
                col=size(D,2);
                if strcmp(app.TrimmingmethodButtonGroup.SelectedObject.Text,'Convex')
                    %Solve the linear equation coefficients formed by adjacent key points
                    a=zeros(keypointnum,1);b=zeros(keypointnum,1);c=zeros(keypointnum,1);
                    for k=1:keypointnum
                        if k~=keypointnum
                            [a(k),b(k),c(k)]=solve_line(keypoint(k+1,:),keypoint(k,:));
                        else
                            [a(k),b(k),c(k)]=solve_line(keypoint(1,:),keypoint(k,:));
                        end
                    end

                    reserve=zeros(row*col,2);
                    reserve_idx=1;
                    for i=1:col %x
                        for j=1:row %y
                            P=[i,j];
                            % Determine whether evey point is within the area
                            % where each line composed of key points intersects
                            isinner=1;
                            for k=1:keypointnum
                                % Jugde if it is on the same side of the line as the inner point
                                if k-1~=0
                                    if (a(k)*keypoint(k-1,1)+b(k)*keypoint(k-1,2)+c(k))*(a(k)*i+b(k)*j+c(k))<=0
                                        isinner=0;
                                        break;
                                    end
                                else
                                    if (a(k)*keypoint(keypointnum,1)+b(k)*keypoint(keypointnum,2) ...
                                            +c(k))*(a(k)*i+b(k)*j+c(k))<=0
                                        isinner=0;
                                        break;
                                    end
                                end
                            end
                            if isinner
                                reserve(reserve_idx,:)=P;
                                reserve_idx=reserve_idx+1;
                            end
                        end
                    end
                else % Nonconvex
                    reserve=zeros(row*col,2);
                    reserve_idx=1;
                    gray_poly=polyshape(keypoint(:,1),keypoint(:,2));
                    for i=1:col
                        for j=1:row
                            if gray_poly.isinterior(i,j)
                                reserve(reserve_idx,:)=[i,j];
                                reserve_idx=reserve_idx+1;
                            end
                        end
                    end
                end

                for i=1:keypointnum
                    reserve(reserve_idx,:)=round(keypoint(i,:));
                    reserve_idx=reserve_idx+1;
                end
                reserve((sum(abs(reserve-[0,0]),2)==0),:)=[]; 
                reserve=(reserve(:,1)-1)*row+reserve(:,2);

                % Convert to 3D point
                [h,w] = size(D);
                u=repmat(1:w,[h,1]);
                v=repmat(1:h,[w,1])';

                ori_Z=double(D);
                ori_X=(ori_Z(:).*(u(:)-u0))/fdx;
                ori_Y=(ori_Z(:).*(v(:)-v0))/fdy;
                ori_Z=reshape(ori_Z,[],1);

                ori_V=[ori_X,ori_Y,ori_Z];

                ori_V=ori_V-mean(ori_V);
                ori_V=ori_V/sqrt(sum(sum(ori_V.^2)));

                ori_X=ori_V(:,1);
                ori_Y=ori_V(:,2);
                ori_Z=ori_V(:,3);

                X=ori_X(reserve);
                Y=ori_Y(reserve);
                Z=ori_Z(reserve);

                V_0=[X,Y,Z];
                V_0=rmoutliers(V_0,"mean");
                ptcloud=pointCloud(V_0);
                downlevel=app.DownsamplelevelSpinner.Value;
                if downlevel~=0
                    ptcloud=pcdownsample(ptcloud,"gridAverage",0.000005*2^(downlevel-1));
                end

                % Mesh reconstruction using Poisson reconstruction method
                Smesh=pc2surfacemesh(ptcloud,"poisson");

                V_ori=Smesh.Vertices;
                T_ori=Smesh.Faces;
                VX=V_ori(:,1);
                VY=V_ori(:,2);
                VZ=V_ori(:,3);
                % extract keypoints in 3D space
                Vki=zeros(keypointnum,3);
                VX_key=zeros(keypointnum,1);
                VY_key=zeros(keypointnum,1);
                VZ_key=zeros(keypointnum,1);
                for i=1:keypointnum
                    VX_key(i)=ori_X(reserve(end-keypointnum+i));
                    VY_key(i)=ori_Y(reserve(end-keypointnum+i));
                    VZ_key(i)=ori_Z(reserve(end-keypointnum+i));
                    Vki(i,:)=[VX_key(i),VY_key(i),VZ_key(i)];
                end
                % Fit a plane with key points
                VXY=[VX_key,VY_key,ones(length(VX_key),1)];
                coef=regress(VZ_key,VXY);

                if strcmp(app.TrimmingmethodButtonGroup.SelectedObject.Text,'Convex')
                    N1=coef(1);N2=coef(2);N3=-1;
                    A=zeros(keypointnum,1);
                    B=zeros(keypointnum,1);
                    C=zeros(keypointnum,1);
                    D=zeros(keypointnum,1);
                    %Solve the coefficients of planes
                    for i=1:keypointnum
                        if i~=keypointnum
                            [A(i),B(i),C(i),D(i)]=solve_surface(Vki(i,:),Vki(i+1,:),N1,N2,N3);
                        else
                            [A(i),B(i),C(i),D(i)]=solve_surface(Vki(i,:),Vki(1,:),N1,N2,N3);
                        end
                    end

                    % Find the interior point where three planes intersect
                    idxi=cell(keypointnum,1);
                    for i=1:keypointnum
                        % Using key points that are not on the plane as discriminant points
                        if i-1~=0
                            if A(i)*Vki(i-1,1)+B(i)*Vki(i-1,2)+C(i)*Vki(i-1,3)+D(i)<=0
                                idxi{i}=find(A(i)*V_ori(:,1)+B(i)*V_ori(:,2)+C(i)*V_ori(:,3)+D(i)<=0);
                            else
                                idxi{i}=find(A(i)*V_ori(:,1)+B(i)*V_ori(:,2)+C(i)*V_ori(:,3)+D(i)>=0);
                            end
                        else
                            if A(i)*Vki(keypointnum,1)+B(i)*Vki(keypointnum,2)...
                                +C(i)*Vki(keypointnum,3)+D(i)<=0
                                idxi{i}=find(A(i)*V_ori(:,1)+B(i)*V_ori(:,2)+C(i)*V_ori(:,3)+D(i)<=0);
                            else
                                idxi{i}=find(A(i)*V_ori(:,1)+B(i)*V_ori(:,2)+C(i)*V_ori(:,3)+D(i)>=0);
                            end
                        end
                        if i==1
                            idx=idxi{i};
                        else
                            idx=intersect(idx,idxi{i});
                        end
                    end
                else
                    % Map the point set to the fitted plane
                    % Ax+By+Cz+D=0 A=coef(1),B=coef(2),C=-1,D=coef(3)
                    VX_map=VX-coef(1)*(coef(1)*VX+coef(2)*VY-VZ+coef(3))/(coef(1)^2+coef(2)^2+1);
                    VY_map=VY-coef(2)*(coef(1)*VX+coef(2)*VY-VZ+coef(3))/(coef(1)^2+coef(2)^2+1);
                    VZ_map=VZ+(coef(1)*VX+coef(2)*VY-VZ+coef(3))/(coef(1)^2+coef(2)^2+1);
                    VX_key_map=VX_key-coef(1)*(coef(1)*VX_key ...
                        +coef(2)*VY_key-VZ_key+coef(3))/(coef(1)^2+coef(2)^2+1);
                    VY_key_map=VY_key-coef(2)*(coef(1)*VX_key ...
                        +coef(2)*VY_key-VZ_key+coef(3))/(coef(1)^2+coef(2)^2+1);
                    VZ_key_map=VZ_key+(coef(1)*VX_key ...
                        +coef(2)*VY_key-VZ_key+coef(3))/(coef(1)^2+coef(2)^2+1);

                    % Rotate point to xOy plane
                    fitplane_n=[coef(1),coef(2),-1];
                    xOy_n=[0,0,1];
                    theta=acos(dot(fitplane_n,xOy_n)/(norm(fitplane_n)*norm(xOy_n))); %Rotation angle
                    rotate_axis=cross(fitplane_n,xOy_n);% Rotation axis
                    rotate_axis=rotate_axis/norm(rotate_axis);
                    V_r=Rotate_point(app,[VX_map,VY_map,VZ_map],theta,rotate_axis);
                    V_key_r=Rotate_point(app,[VX_key_map,VY_key_map,VZ_key_map],theta,rotate_axis);

                    % Map the rotate point to 2D space
                    VX_r=V_r(:,1);
                    VY_r=V_r(:,2);
                    VX_key_r=V_key_r(:,1);
                    VY_key_r=V_key_r(:,2);
                    poly=polyshape(VX_key_r,VY_key_r);
                    idx=zeros(length(VX_r),1);
                    for i=1:length(VX_r)
                        if poly.isinterior(VX_r(i),VY_r(i))
                            idx(i)=i;
                        end
                    end
                    idx(idx==0)=[];
                end

                is_retain1=ismember(T_ori(:,1),idx);
                is_retain2=ismember(T_ori(:,2),idx);
                is_retain3=ismember(T_ori(:,3),idx);
                is_retain=is_retain1&is_retain2&is_retain3;

                T=T_ori(is_retain,:);
                V=V_ori(unique(T),:);

                if isempty(V)
                    Output_Info(app,['The polygon of file ',dataname,' is non convex']);
                    Output_Info(app,'Please try replacing the clipping method');
                    Cannotmap=Cannotmap+1;
                    continue;
                end

                %Reorder triangle sequence
                Isn=1;%Incremental sequence number
                T_temp=T;
                for i=1:length(V)
                    [minr,minc]=find(T_temp==min(min(T_temp)));
                    for j=1:length(minr)
                        T(minr(j),minc(j))=Isn;
                        T_temp(minr(j),minc(j))=inf;
                    end
                    Isn=Isn+1;
                end

                temp_mesh=surfaceMesh(V,T);
                smooth_level=app.SmoothlevelSpinner.Value;
                if smooth_level~=0
                    temp_mesh=smoothSurfaceMesh(temp_mesh,smooth_level);
                end
                temp_mesh.simplify;
                temp_mesh.removeDefects("unreferenced-vertices");
                V_sou=double(temp_mesh.Vertices);
                T_sou=double(temp_mesh.Faces);
                tri=triangulation(T_sou,V_sou);
                b_sou=tri.freeBoundary();
                b_sou=b_sou(:,1);

                % Using the position of the first keypoint as the starting index
                nearest_k1_idx=dsearchn(V_sou(b_sou,:),Vki(1,:));
                b_sou=[b_sou(nearest_k1_idx:end);b_sou(1:nearest_k1_idx-1)];

                % search the corners around the egde
                if keypointnum==3
                    nearest_V1_idx=dsearchn(V_sou(b_sou,:),Vki(1,:));
                    nearest_V2_idx=dsearchn(V_sou(b_sou,:),Vki(2,:));
                    nearest_V3_idx=dsearchn(V_sou(b_sou,:),Vki(3,:));
                    sel_cones_sou=[nearest_V1_idx,nearest_V2_idx,nearest_V3_idx];
                else % If the key points are greater than or equal to 4, select 4 cone points
                    interval=keypointnum/4;
                    nearest_V1_idx=dsearchn(V_sou(b_sou,:),Vki(round(interval),:));
                    nearest_V2_idx=dsearchn(V_sou(b_sou,:),Vki(round(interval*2),:));
                    nearest_V3_idx=dsearchn(V_sou(b_sou,:),Vki(round(interval*3),:));
                    nearest_V4_idx=dsearchn(V_sou(b_sou,:),Vki(round(interval*4),:));
                    sel_cones_sou=[nearest_V1_idx,nearest_V2_idx,nearest_V3_idx,nearest_V4_idx];
                end
                sel_cones_sou=sort(sel_cones_sou);

                setGlobalx(0);
                V_mapped=disk_map(V_tar,T_tar,b_tar,V_sou,T_sou,b_sou, ...
                    sel_cones_tar,sel_cones_sou);
                if getGlobalx
                    Output_Info(app,['file ',dataname,' can not be map.']);
                    Cannotmap=Cannotmap+1;
                    continue;
                end
                % Write the mapped data
                mesh_map=surfaceMesh(V_mapped,T_tar);
                if ~exist(fullfile(depthpath,'mesh'),'file')
                    mkdir(fullfile(depthpath,'mesh'));
                end
                writeSurfaceMesh(mesh_map,fullfile(fullfile(depthpath,'mesh'),[dataname,'.ply']));
            end
            close(Bar);


            Output_Info(app,'Starting scoring...');
            % Calculate scores of all file
            if ~strcmp(app.FileordirectoryButtonGroup.SelectedObject.Text,'Single')
                mul_filename=string(zeros(fnum,1));
            end
            if strcmp(app.Difference_calculate_spaceDropDown.Value,'Basis vector')
                V_T_in_basis=basis_vectors\reshape(V_T',[],1);
            end
            Bar2=waitbar(0,'Scoring');
            for i=1:fnum
                waitbar(i/fnum,Bar2,'Scoring');
                if strcmp(app.FileordirectoryButtonGroup.SelectedObject.Text,'Single')
                    dataname=split(filename,".");
                    dataname=dataname{1};
                    if ~exist(fullfile(fullfile(depthpath,'mesh'),[dataname,'.ply']),'file')
                        score(i)=NaN;
                        continue;
                    end
                    V_S=readSurfaceMesh(fullfile(fullfile(depthpath,'mesh'),[dataname,'.ply'])).Vertices;
                else
                    dataname=split(file(i).name,".");
                    dataname=dataname{1};
                    mul_filename(i)=file(i).name;
                    if ~exist(fullfile(fullfile(depthpath,'mesh'),[dataname,'.ply']),'file')
                        score(i)=NaN;
                        continue;
                    end
                    V_S=readSurfaceMesh(fullfile(fullfile(depthpath,'mesh'),[dataname,'.ply'])).Vertices;
                end
                V_S=V_S-mean(V_S);
                V_S=V_S/sqrt(sum(sum(V_S.^2)));

                [~,V_S,~]=procrustes(V_T,V_S);
                if strcmp(app.Difference_calculate_spaceDropDown.Value,'Basis vector')
                    V_S_in_basis=basis_vectors\reshape(V_S',[],1);
                    score(i)=evaluate_score(V_S_in_basis,V_T_in_basis,Faces,'basis_vector');
                else
                    score(i)=evaluate_score(V_S,V_T,Faces,'euclidean');
                end
            end

            % Project calculation result to discrete BCS scores
            
            norm_score=(score-min_score)/(max_score-min_score)*(max_BCS-min_BCS)+min_BCS;
            if strcmp(app.FileordirectoryButtonGroup.SelectedObject.Text,'Single')
                app.UITable3.Data=table(string(filename),norm_score);
            else
                app.UITable3.Data=table(mul_filename,norm_score);
            end
            close(Bar2);    
            Output_Info(app,'Calculate complete.');
        end

        function my_closereq(~,fig)
            close_selection = uiconfirm(fig,'Close the figure window?',...
                'Confirmation');
            switch close_selection
                case 'OK'
                    delete(fig);
                case 'Cancel'
                    return
            end
        end
        
        function Detect(app)
            % @article{Lauer2022MultianimalPE,
            % title={Multi-animal pose estimation, identification and tracking with DeepLabCut},
            % author={Jessy Lauer and Mu Zhou and Shaokai Ye and William Menegas and
            %   Steffen Schneider and Tanmay Nath and Mohammed Mostafizur Rahman and
            %   Valentina Di Santo and Daniel Soberanes and Guoping Feng and
            %   Venkatesh N. Murthy and George Lauder and Catherine Dulac and
            %   M. Mathis and Alexander Mathis},
            % journal={Nature Methods},
            % year={2022},
            % volume={19},
            % pages={496 - 504}}
            Output_Info(app,'Opening DLC...');
            status=system('conda activate DEEPLABCUT && python -m deeplabcut');
            if status
                errordlg('DLC startup failed, the environment may not have been installed yet.');
            end
        end
    end

    methods (Access = public)

        function Depth_to_Mesh(app)
            %% Convert the depth image to mesh with the same topology
            t0=tic;
            fullpath=mfilename("fullpath");
            [Mainpath,~]=fileparts(fullpath);
            Mainpath=string(Mainpath);
            markpath=strcat(Mainpath,"\",app.project_name,"\Preprocessed\gray\");
            Depthpath=(strcat(Mainpath,"\",app.project_name,"\Preprocessed\depth\"));

            % Read the raw images
            Depthfile=dir(strcat(Depthpath,"*.png"));
            Nof=length(Depthfile); % Number of file

            if ~exist(strcat(Mainpath,"\",app.project_name,"\Preprocessed\depth\"),'file')
                errordlg('The depth image has not been imported. Please import it first');
                return;
            end

            fx=str2double(app.fxTextArea.Value);
            fy=str2double(app.fyTextArea.Value);
            cx=str2double(app.cxTextArea.Value);
            cy=str2double(app.cyTextArea.Value);

            %% Extract the predicted marker point coordinates
            csvfile=dir(strcat(markpath,"*.csv"));
            csvfilenum=length(csvfile);
            if csvfilenum==0
                errordlg('Key points have not been detected yet.','No key point infomation');
                return;
            end

            % Select the file with the latest creation time
            csvdate=string(zeros(csvfilenum,1));
            for i=1:csvfilenum
                csvdate(i)=string(csvfile(i).date);
            end
            [~,csvidx]=sort(csvdate);
            csvfile=csvfile(csvidx(end));

            % Clear the old file
            delete(strcat(Mainpath,"\",app.project_name,"\Preprocessed\mesh\*"));
            delete(strcat(Mainpath,"\",app.project_name,"\Preprocessed\pointcloud\*"));

            % Reset Control State
            app.IndicatorlightLamp.Color=[0,1,0];
            pause(1e-6);

            marktable=readtable(strcat(markpath,string(csvfile.name)));
            markpoint=table2array(marktable);
            marknum=floor(size(marktable,2)/3);
            likelihood=zeros(Nof,marknum);
            for i=0:marknum
                if i~=marknum
                    likelihood(:,marknum-i)=markpoint(:,(marknum-i)*3+1);
                end
                markpoint(:,(marknum-i)*3+1)=[];
            end

            %% Initialize calibration parameters of  camera
            intrinsic=[fx,0,cx
                0,fy,cy
                0,0,1];

            fdx=intrinsic(1,1);
            fdy=intrinsic(2,2);
            u0=intrinsic(1,3);
            v0=intrinsic(2,3);

            Cannotmap=0;
            Bar=waitbar(0,'Converting');
            Output_Info(app,"Start convert to mesh");
            
            %% Process each image
            for n=1:Nof
                if ~isvalid(Bar)
                    app.IndicatorlightLamp.Color=[1,0,0];
                    Output_Info(app,'Stop convert');
                    errordlg('User stop','User Stop');
                    return;
                end
                waitbar(n/Nof,Bar);
                pause(1e-6);

                dataname=split(Depthfile(n).name,["_","."]);
                dataname=dataname{2};

                D=imread(strcat(Depthpath,string(Depthfile(n).name)));
                row=size(D,1);
                col=size(D,2);

                corner_coor=markpoint(n,:);
                keypointnum=length(corner_coor)/2;
                keypoint=zeros(keypointnum,2);

                isdepthmissing=0;
                for i=1:keypointnum
                    keypoint(i,:)=round(corner_coor([(i-1)*2+1,(i-1)*2+2]));
                    if D(keypoint(i,2),keypoint(i,1))==0
                        Output_Info(app,['The depth of the key point of file ',Depthfile(n).name,' is zeros ',...
                            'and cannot be converted to mesh.']);
                        Cannotmap=Cannotmap+1;
                        isdepthmissing=1;
                        break;
                    end
                end
                if isdepthmissing
                    continue;
                end

                isKeypointDeviation=0;
                for i=1:keypointnum
                    if likelihood(n,i)<0.95
                        Output_Info(app,['Number ',dataname,' key point position deviation']);
                        Cannotmap=Cannotmap+1;
                        isKeypointDeviation=1;
                        break;
                    end
                end
                if isKeypointDeviation
                    continue;
                end

                if strcmp(app.TrimmingmethodButtonGroup.SelectedObject.Text,'Convex')
                    %Solve the linear equation coefficients formed by adjacent key points
                    a=zeros(keypointnum,1);b=zeros(keypointnum,1);c=zeros(keypointnum,1);
                    for k=1:keypointnum
                        if k~=keypointnum
                            [a(k),b(k),c(k)]=solve_line(keypoint(k+1,:),keypoint(k,:));
                        else
                            [a(k),b(k),c(k)]=solve_line(keypoint(1,:),keypoint(k,:));
                        end
                    end

                    reserve=zeros(row*col,2);
                    reserve_idx=1;

                    for i=1:col %x
                        for j=1:row %y
                            P=[i,j];
                            % Determine whether evey point is within the area
                            % where each line composed of key points intersects
                            isinner=1;
                            for k=1:keypointnum
                                % Jugde if it is on the same side of the line as the inner point
                                if k-1~=0
                                    if (a(k)*keypoint(k-1,1)+b(k)*keypoint(k-1,2)+c(k))*(a(k)*i+b(k)*j+c(k))<=0
                                        isinner=0;
                                        break;
                                    end
                                else
                                    if (a(k)*keypoint(keypointnum,1)+b(k)*keypoint(keypointnum,2) ...
                                            +c(k))*(a(k)*i+b(k)*j+c(k))<=0
                                        isinner=0;
                                        break;
                                    end
                                end
                            end
                            if isinner
                                reserve(reserve_idx,:)=P;
                                reserve_idx=reserve_idx+1;
                            end
                        end
                    end
                else % Nonconvex
                    reserve=zeros(row*col,2);
                    reserve_idx=1;
                    gray_poly=polyshape(keypoint(:,1),keypoint(:,2));
                    for i=1:col
                        for j=1:row
                            if gray_poly.isinterior(i,j)
                                reserve(reserve_idx,:)=[i,j];
                                reserve_idx=reserve_idx+1;
                            end
                        end
                    end
                end

                for i=1:keypointnum %Add the keypoint
                    reserve(reserve_idx,:)=round(keypoint(i,:));
                    reserve_idx=reserve_idx+1;
                end
                
                reserve((sum(abs(reserve-[0,0]),2)==0),:)=[]; % Remove excess rows
                reserve=(reserve(:,1)-1)*row+reserve(:,2);

                % Convert to 3D point
                [h,w] = size(D);
                u=repmat(1:w,[h,1]);
                v=repmat(1:h,[w,1])';

                ori_Z=double(D);
                ori_X=(ori_Z(:).*(u(:)-u0))/fdx;
                ori_Y=(ori_Z(:).*(v(:)-v0))/fdy;
                ori_Z=reshape(ori_Z,[],1);

                ori_V=[ori_X,ori_Y,ori_Z];

                ori_V=ori_V-mean(ori_V);
                ori_V=ori_V/sqrt(sum(sum(ori_V.^2)));

                ori_X=ori_V(:,1);
                ori_Y=ori_V(:,2);
                ori_Z=ori_V(:,3);

                X=ori_X(reserve);
                Y=ori_Y(reserve);
                Z=ori_Z(reserve);

                V_0=[X,Y,Z];
                V_0=rmoutliers(V_0,"mean");
                ptcloud=pointCloud(V_0);
                downlevel=app.DownsamplelevelSpinner.Value;
                if downlevel~=0
                    ptcloud=pcdownsample(ptcloud,"gridAverage",0.000005*2^(downlevel-1));
                end
                if app.WriteoriginalpointcloudCheckBox.Value
                    if ~exist(strcat(Mainpath,"\",app.project_name,"\Preprocessed\pointcloud\"),'file')
                        mkdir(strcat(Mainpath,"\",app.project_name,"\Preprocessed\pointcloud\"));
                    end
                    pcwrite(ptcloud,strcat(Mainpath,"\",app.project_name,"\Preprocessed\pointcloud\pc_",dataname,".ply"));
                end

                % Mesh reconstruction using Poisson reconstruction method
                Smesh=pc2surfacemesh(ptcloud,"poisson");

                V_ori=Smesh.Vertices;
                T_ori=Smesh.Faces;
                VX=V_ori(:,1);
                VY=V_ori(:,2);
                VZ=V_ori(:,3);
                % extract keypoints in 3D space
                Vki=zeros(keypointnum,3);
                VX_key=zeros(keypointnum,1);
                VY_key=zeros(keypointnum,1);
                VZ_key=zeros(keypointnum,1);
                for i=1:keypointnum
                    VX_key(i)=ori_X(reserve(end-keypointnum+i));
                    VY_key(i)=ori_Y(reserve(end-keypointnum+i));
                    VZ_key(i)=ori_Z(reserve(end-keypointnum+i));
                    Vki(i,:)=[VX_key(i),VY_key(i),VZ_key(i)];
                end
                % Fit a plane with key points
                VXY=[VX_key,VY_key,ones(length(VX_key),1)];
                coef=regress(VZ_key,VXY);

                if strcmp(app.TrimmingmethodButtonGroup.SelectedObject.Text,'Convex')
                    N1=coef(1);N2=coef(2);N3=-1;
                    A=zeros(keypointnum,1);
                    B=zeros(keypointnum,1);
                    C=zeros(keypointnum,1);
                    D=zeros(keypointnum,1);
                    %Solve the coefficients of planes
                    for i=1:keypointnum
                        if i~=keypointnum
                            [A(i),B(i),C(i),D(i)]=solve_surface(Vki(i,:),Vki(i+1,:),N1,N2,N3);
                        else
                            [A(i),B(i),C(i),D(i)]=solve_surface(Vki(i,:),Vki(1,:),N1,N2,N3);
                        end
                    end

                    % Find the interior point where three planes intersect
                    idxi=cell(keypointnum,1);
                    for i=1:keypointnum
                        % Using key points that are not on the plane as discriminant points
                        if i-1~=0
                            if A(i)*Vki(i-1,1)+B(i)*Vki(i-1,2)+C(i)*Vki(i-1,3)+D(i)<=0
                                idxi{i}=find(A(i)*V_ori(:,1)+B(i)*V_ori(:,2)+C(i)*V_ori(:,3)+D(i)<=0);
                            else
                                idxi{i}=find(A(i)*V_ori(:,1)+B(i)*V_ori(:,2)+C(i)*V_ori(:,3)+D(i)>=0);
                            end
                        else
                            if A(i)*Vki(keypointnum,1)+B(i)*Vki(keypointnum,2)...
                                +C(i)*Vki(keypointnum,3)+D(i)<=0
                                idxi{i}=find(A(i)*V_ori(:,1)+B(i)*V_ori(:,2)+C(i)*V_ori(:,3)+D(i)<=0);
                            else
                                idxi{i}=find(A(i)*V_ori(:,1)+B(i)*V_ori(:,2)+C(i)*V_ori(:,3)+D(i)>=0);
                            end
                        end
                        if i==1
                            idx=idxi{i};
                        else
                            idx=intersect(idx,idxi{i});
                        end
                    end
                else
                    % Map the point set to the fitted plane
                    % Ax+By+Cz+D=0 A=coef(1),B=coef(2),C=-1,D=coef(3)
                    VX_map=VX-coef(1)*(coef(1)*VX+coef(2)*VY-VZ+coef(3))/(coef(1)^2+coef(2)^2+1);
                    VY_map=VY-coef(2)*(coef(1)*VX+coef(2)*VY-VZ+coef(3))/(coef(1)^2+coef(2)^2+1);
                    VZ_map=VZ+(coef(1)*VX+coef(2)*VY-VZ+coef(3))/(coef(1)^2+coef(2)^2+1);
                    VX_key_map=VX_key-coef(1)*(coef(1)*VX_key ...
                        +coef(2)*VY_key-VZ_key+coef(3))/(coef(1)^2+coef(2)^2+1);
                    VY_key_map=VY_key-coef(2)*(coef(1)*VX_key ...
                        +coef(2)*VY_key-VZ_key+coef(3))/(coef(1)^2+coef(2)^2+1);
                    VZ_key_map=VZ_key+(coef(1)*VX_key ...
                        +coef(2)*VY_key-VZ_key+coef(3))/(coef(1)^2+coef(2)^2+1);

                    % Rotate point to xOy plane
                    fitplane_n=[coef(1),coef(2),-1];
                    xOy_n=[0,0,1];
                    theta=acos(dot(fitplane_n,xOy_n)/(norm(fitplane_n)*norm(xOy_n))); %Rotation angle
                    rotate_axis=cross(fitplane_n,xOy_n);% Rotation axis
                    rotate_axis=rotate_axis/norm(rotate_axis);
                    V_r=Rotate_point(app,[VX_map,VY_map,VZ_map],theta,rotate_axis);
                    V_key_r=Rotate_point(app,[VX_key_map,VY_key_map,VZ_key_map],theta,rotate_axis);

                    % Map the rotate point to 2D space
                    VX_r=V_r(:,1);
                    VY_r=V_r(:,2);
                    VX_key_r=V_key_r(:,1);
                    VY_key_r=V_key_r(:,2);
                    poly=polyshape(VX_key_r,VY_key_r);
                    idx=zeros(length(VX_r),1);
                    for i=1:length(VX_r)
                        if poly.isinterior(VX_r(i),VY_r(i))
                            idx(i)=i;
                        end
                    end
                    idx(idx==0)=[];
                end

                % Preserve faces and vertices within the range
                is_retain1=ismember(T_ori(:,1),idx);
                is_retain2=ismember(T_ori(:,2),idx);
                is_retain3=ismember(T_ori(:,3),idx);
                is_retain=is_retain1&is_retain2&is_retain3;
                T=T_ori(is_retain,:);
                V=V_ori(unique(T),:);

                if isempty(V)
                    Output_Info(app,['The polygon of Number ',dataname,' maybe non convex']);
                    Cannotmap=Cannotmap+1;
                    continue;
                end

                % Reorder index of the faces
                Isn=1;%Incremental sequence number
                T_temp=T;
                for i=1:length(V)
                    [minr,minc]=find(T_temp==min(min(T_temp)));
                    for j=1:length(minr)
                        T(minr(j),minc(j))=Isn;
                        T_temp(minr(j),minc(j))=inf;
                    end
                    Isn=Isn+1;
                end

                % Mapping to obtain meshes with the same topology
                % Aigerman N, Lipman Y. Orbifold tutte embeddings[J]. ACM Trans. Graph., 
                %   2015, 34(6): 190:1-190:12.
                if ~exist('V_tar','var') %Determine whether the benchmark mesh has been created
                    temp_mesh=surfaceMesh(V,T);
                    smooth_level=app.SmoothlevelSpinner.Value;
                    if smooth_level~=0
                        temp_mesh=smoothSurfaceMesh(temp_mesh,smooth_level);
                    end
                    temp_mesh.simplify;
                    temp_mesh.removeDefects("unreferenced-vertices");
                    V_tar=double(temp_mesh.Vertices);
                    T_tar=double(temp_mesh.Faces);
                    tri=triangulation(T_tar,V_tar);
                    b_tar=tri.freeBoundary();
                    b_tar=b_tar(:,1);

                    % Using the position of the first keypoint as the starting index
                    nearest_k1_idx=dsearchn(V_tar(b_tar,:),Vki(1,:));
                    b_tar=[b_tar(nearest_k1_idx:end);b_tar(1:nearest_k1_idx-1)];

                    model_key_idx=zeros(keypointnum,1);
                    for i=1:keypointnum
                        model_key_idx(i)=dsearchn(V_tar(b_tar,:),Vki(i,:));
                    end
                    model_key_idx=b_tar(model_key_idx);
                    save(strcat(Mainpath,"\",app.project_name,"\model_key_idx.mat"),"model_key_idx");
                    % search the corners around the egde
                    if keypointnum==3
                        nearest_V1_idx_tar=dsearchn(V_tar(b_tar,:),Vki(1,:));
                        nearest_V2_idx_tar=dsearchn(V_tar(b_tar,:),Vki(2,:));
                        nearest_V3_idx_tar=dsearchn(V_tar(b_tar,:),Vki(3,:));
                        sel_cones_tar=[nearest_V1_idx_tar,nearest_V2_idx_tar,nearest_V3_idx_tar];
                    else % If the key points are greater than or equal to 4, select 4 cone points
                        interval=keypointnum/4;
                        nearest_V1_idx_tar=dsearchn(V_tar(b_tar,:),Vki(round(interval),:));
                        nearest_V2_idx_tar=dsearchn(V_tar(b_tar,:),Vki(round(interval*2),:));
                        nearest_V3_idx_tar=dsearchn(V_tar(b_tar,:),Vki(round(interval*3),:));
                        nearest_V4_idx_tar=dsearchn(V_tar(b_tar,:),Vki(round(interval*4),:));
                        sel_cones_tar=[nearest_V1_idx_tar,nearest_V2_idx_tar,...
                            nearest_V3_idx_tar,nearest_V4_idx_tar];
                    end
                    sel_cones_tar=sort(sel_cones_tar);
                    fullpath=mfilename("fullpath");
                    Mainpath=fileparts(fullpath);

                    if ~exist(strcat(Mainpath,"\",app.project_name,"\Preprocessed\mesh\"),'file')
                        mkdir(strcat(Mainpath,"\",app.project_name,"\Preprocessed\mesh\"));
                    end
                    if app.AddNonrigidtransformCheckBox.Value
                        moving=pointCloud(V_tar);
                        [~,movingReg]=pcregistercpd(moving,ptcloud);
                        V_tar=movingReg.Location;
                    end
                    tar_mesh=surfaceMesh(V_tar,T_tar);
                    writeSurfaceMesh(tar_mesh,strcat(Mainpath,"\",app.project_name,"\Preprocessed\mesh\mesh_",dataname,".ply"));
                else % Benchmark mesh already exists
                    temp_mesh=surfaceMesh(V,T);
                    smooth_level=app.SmoothlevelSpinner.Value;
                    if smooth_level~=0
                        temp_mesh=smoothSurfaceMesh(temp_mesh,smooth_level);
                    end
                    temp_mesh.simplify;
                    temp_mesh.removeDefects("unreferenced-vertices");
                    V_sou=double(temp_mesh.Vertices);
                    T_sou=double(temp_mesh.Faces);
                    tri=triangulation(T_sou,V_sou);
                    b_sou=tri.freeBoundary();
                    b_sou=b_sou(:,1);

                    % Using the position of the first keypoint as the starting index
                    nearest_k1_idx=dsearchn(V_sou(b_sou,:),Vki(1,:));
                    b_sou=[b_sou(nearest_k1_idx:end);b_sou(1:nearest_k1_idx-1)];

                    % search the corners around the egde
                    if keypointnum==3
                        nearest_V1_idx=dsearchn(V_sou(b_sou,:),Vki(1,:));
                        nearest_V2_idx=dsearchn(V_sou(b_sou,:),Vki(2,:));
                        nearest_V3_idx=dsearchn(V_sou(b_sou,:),Vki(3,:));
                        sel_cones_sou=[nearest_V1_idx,nearest_V2_idx,nearest_V3_idx];
                    else % If the key points are greater than or equal to 4, select 4 cone points
                        interval=keypointnum/4;
                        nearest_V1_idx=dsearchn(V_sou(b_sou,:),Vki(round(interval),:));
                        nearest_V2_idx=dsearchn(V_sou(b_sou,:),Vki(round(interval*2),:));
                        nearest_V3_idx=dsearchn(V_sou(b_sou,:),Vki(round(interval*3),:));
                        nearest_V4_idx=dsearchn(V_sou(b_sou,:),Vki(round(interval*4),:));
                        sel_cones_sou=[nearest_V1_idx,nearest_V2_idx,nearest_V3_idx,nearest_V4_idx];
                    end
                    sel_cones_sou=sort(sel_cones_sou);

                    setGlobalx(0);
                    V_mapped=disk_map(V_tar,T_tar,b_tar,V_sou,T_sou,b_sou,sel_cones_tar,sel_cones_sou);
                    if getGlobalx
                        Output_Info(app,['Number ',dataname,' can not be map.']);
                        Cannotmap=Cannotmap+1;
                        continue;
                    end
                    % Write the mapped data
                    if exist(strcat(Mainpath,"\",app.project_name,"\Preprocessed\mesh\"),'file')==0
                        mkdir(strcat(Mainpath,"\",app.project_name,"\Preprocessed\mesh\"));
                    end
                    if app.AddNonrigidtransformCheckBox.Value
                        moving=pointCloud(V_mapped);
                        [~,movingReg]=pcregistercpd(moving,ptcloud);
                        V_mapped=movingReg.Location;
                    end
                    mesh_map=surfaceMesh(V_mapped,T_tar);
                    writeSurfaceMesh(mesh_map,strcat(Mainpath,"\",app.project_name,"\Preprocessed\mesh\mesh_",dataname,".ply"));
                end
            end
            close(Bar);
            app.IndicatorlightLamp.Color=[1,0,0];
            Output_Info(app,"Convert complete");
            str=sprintf('Total %d mesh reconstruction failures',Cannotmap);
            Output_Info(app,str);
            t_convert=toc(t0);
            Output_Info(app,['Lasting for ',num2str(t_convert),' seconds']);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, project_name)
            app.cellArrayText{1}=sprintf('%s\n', '***Output prompt prompt here***');
            app.InfoOutTextArea.Value=app.cellArrayText{1};
            app.fxTextArea.Value='364.032';
            app.fyTextArea.Value='364.032';
            app.cxTextArea.Value='258.891';
            app.cyTextArea.Value='209.32';
            app.DiscretizationincrementEditField.Value=0.25;
            app.DisplayincrementEditField.Value=0.25;
            app.LowerlimitofvalueEditField.Value=0;
            app.UpperlimitofvalueEditField.Value=9;
            movegui(app.Shape_analysisUIFigure,'center');
            app.Shape_analysisUIFigure.CloseRequestFcn=@(src,event)my_closereq(app,src);
            app.Shape_analysisUIFigure.Name=['Shape_analysis_',project_name];
            app.project_name=project_name;
        end

        % Menu selected function: ImportMenu
        function ImportMenuSelected(app, event)
            path=uigetdir('C:/','Select a diretory that include depth image with extension of png');
            if isequal(path,0)
                Output_Info(app,'User selected Cancel');
                return;
            else
                Output_Info(app,['User selected ', path]);
                file=dir(fullfile(path,'*.png'));
                if isempty(file)
                    errordlg('Could not find an image with the extension png in this path.' ...
                        ,'File not fonud error');
                    return;
                else
                    fullpath=mfilename("fullpath");
                    [Mainpath,~]=fileparts(fullpath);
                    Mainpath=string(Mainpath);
                    filenum=length(file);
                    if exist(strcat(Mainpath,"\",app.project_name,"\Preprocessed\depth\"),'file')
                        rmdir(strcat(Mainpath,"\",app.project_name,"\Preprocessed\depth\"),"s");
                    end
                    if ~exist(strcat(Mainpath,"\",app.project_name,"\Preprocessed\depth\"),'file')
                        mkdir(strcat(Mainpath,"\",app.project_name,"\Preprocessed\depth\"));
                    end
                    Image_Num=string(zeros(filenum,1));
                    origin_filename=string(zeros(filenum,1));
                    for i=1:filenum
                        Digits=floor(log10(filenum))+1;
                        ref=sprintf(['%0',num2str(Digits),'d'],i);
                        Image_Num(i)=string(ref);
                        origin_filename(i)=file(i).name;
                        copyfile([path,'\',file(i).name],strcat(Mainpath,"\",app.project_name,"\Preprocessed\depth\depth_",ref,".png"));
                    end
                    BCS=zeros(filenum,1);
                    Table=table(Image_Num,BCS,origin_filename);
                    writetable(Table,strcat(Mainpath,"\",app.project_name,"\BCS.dat"));
                    Output_Info(app,'Import sucessfully');
                end
            end
        end

        % Menu selected function: ExitMenu
        function ExitMenuSelected(app, event)
            close_selection=uiconfirm(app.Shape_analysisUIFigure,'Close software?','Quit','Icon','warning');
            switch close_selection
                case 'OK'
                    app.delete;
                case 'Cancel'
                    return
            end
        end

        % Menu selected function: WriteVideoMenu
        function WriteVideoMenuSelected(app, event)
            app.TabGroup.SelectedTab=app.WritevideoTab;
            Write_Video(app);
        end

        % Menu selected function: ConvertmeshMenu
        function ConvertmeshMenuSelected(app, event)
            app.TabGroup.SelectedTab=app.ConverttomeshTab;
            Depth_to_Mesh(app);
        end

        % Menu selected function: AddBCSgroundtruthMenu
        function AddBCSgroundtruthMenuSelected(app, event)
            app.TabGroup.SelectedTab=app.InputtruthTab;
            Add_BCS_Truth(app);
        end

        % Menu selected function: EvaluateaccuracyMenu
        function EvaluateaccuracyMenuSelected(app, event)
            app.TabGroup.SelectedTab=app.EvaluateTab;
            Evaluate_Accuracy(app);
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            Add_BCS_Truth(app);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            BCS=app.UITable.Data;
            if ~isempty(BCS)
                fnum=size(BCS,1);
                width=floor(log10(fnum))+1;
                Image_Num=string(zeros(fnum,1));
                for i=1:fnum
                    Image_Num(i)=string(sprintf(['%0',num2str(width),'d'],i));
                end
                BCS=[table(Image_Num),BCS(:,2),BCS(:,3)];
                fullpath=mfilename("fullpath");
                [Mainpath,~]=fileparts(fullpath);
                Mainpath=string(Mainpath);
                writetable(BCS,strcat(Mainpath,"\",app.project_name,"\BCS.dat"));
            end
            Output_Info(app,'Save BCS sucessfully');
        end

        % Button pushed function: StartconvertButton
        function StartconvertButtonPushed(app, event)
            Depth_to_Mesh(app);
        end

        % Button pushed function: StartevaluateButton
        function StartevaluateButtonPushed(app, event)
            Evaluate_Accuracy(app);
        end

        % Menu selected function: KeypointdetectMenu
        function KeypointdetectMenuSelected(app, event)
            app.TabGroup.SelectedTab=app.DetectionTab;
            Detect(app);
        end

        % Menu selected function: TrainMenu
        function TrainMenuSelected(app, event)
            app.TabGroup.SelectedTab=app.TrainTab;
            Train_model(app);
        end

        % Button pushed function: ImportButton
        function ImportButtonPushed(app, event)
            if isempty(app.UITable.Data)
                msgbox('Please push the ''load'' button to load data firstly');
                return;
            end
            [filename,path]=uigetfile({'*.csv;*.xls;*.xlsx;*.txt','Text files (*.csv;*.xls;*.xlsx;*.txt)'}, ...
                ['Select a file that contains only one column of BCS,' ...
                'Note that the serial number matches the original file name.']);
            if isequal(filename,0)
                Output_Info(app,'User selected Cancel');
                return;
            end
            BCS=readtable([path,filename],'VariableNamingRule','preserve');
            if isempty(BCS)
                warndlg('The file content is empty');
                return;
            elseif size(BCS,2)>1
                errordlg(['The file content exceeds one column. ' ...
                    'Please import a file that contains only one column of BCS.']);
                return;
            else
                row=size(app.UITable.DisplayData,1);
                if row~=size(BCS,1)
                    warndlg('The number of imported file rows exceeds of less than the number of files in the table')
                    return;
                end
                app.UITable.Data(:,"BCS")=BCS;
                msgbox('Import successfully');
                Output_Info(app,['User import BCS file ',path,filename]);
            end
        end

        % Button pushed function: WriteaviButton
        function WriteaviButtonPushed(app, event)
            Write_Video(app);
        end

        % Button pushed function: TrainButton
        function TrainButtonPushed(app, event)
            Train_model(app);
        end

        % Button pushed function: ScoreButton
        function ScoreButtonPushed(app, event)
            Score(app);
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            if ~isempty(app.UITable3.Data)
                [file, path] = uiputfile({'*.csv';'*.txt';'*.xls';'*.xlsx';'*.*'},'Save BCS in file');
                if isequal(file,0) || isequal(path,0)
                    Output_Info(app,'User clicked Cancel.');
                else
                    writetable(app.UITable3.Data,fullfile(path,file));
                    Output_Info(app,'Export successful');
                end
            else
                errordlg('Please score BCS firstly','No score error');
            end
        end

        % Menu selected function: SingleMenu
        function SingleMenuSelected(app, event)
            app.FileordirectoryButtonGroup.SelectedObject=app.SingleButton;
            app.TabGroup.SelectedTab=app.ScoreTab;
            Score(app);
        end

        % Menu selected function: MultiplyMenu
        function MultiplyMenuSelected(app, event)
            app.FileordirectoryButtonGroup.SelectedObject=app.MultipleButton;
            app.TabGroup.SelectedTab=app.ScoreTab;
            Score(app);
        end

        % Button pushed function: EditparaButton
        function EditparaButtonPushed(app, event)
            app.TabGroup.SelectedTab=app.ConverttomeshTab;
        end

        % Button pushed function: OpenDLCButton
        function OpenDLCButtonPushed(app, event)
            Detect(app);
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            DLC_path=uigetdir('C:/','Select the DLC diretory');
            if isequal(DLC_path,0)
                Output_Info(app,'User selected Cancel');
                return;
            end
            app.DLCpath.Value=DLC_path;
        end

        % Menu selected function: ReturnMainMenu
        function ReturnMainMenuSelected(app, event)
            Main;
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.Shape_analysisUIFigure = uifigure('Visible', 'off');
            app.Shape_analysisUIFigure.Name = 'Shape_analysis';
            app.Shape_analysisUIFigure.Icon = fullfile(pathToMLAPP, 'Icon', 'Logo.png');
            app.FileMenu = uimenu(app.Shape_analysisUIFigure);
            app.FileMenu.Text = 'File';
            app.ImportMenu = uimenu(app.FileMenu);
            app.ImportMenu.MenuSelectedFcn = createCallbackFcn(app, @ImportMenuSelected, true);
            app.ImportMenu.Text = 'Import';
            app.ReturnMainMenu = uimenu(app.FileMenu);
            app.ReturnMainMenu.MenuSelectedFcn = createCallbackFcn(app, @ReturnMainMenuSelected, true);
            app.ReturnMainMenu.Text = 'Return Main';
            app.ExitMenu = uimenu(app.FileMenu);
            app.ExitMenu.MenuSelectedFcn = createCallbackFcn(app, @ExitMenuSelected, true);
            app.ExitMenu.Text = 'Exit';
            app.PreprocessMenu = uimenu(app.Shape_analysisUIFigure);
            app.PreprocessMenu.Text = 'Preprocess';
            app.WriteVideoMenu = uimenu(app.PreprocessMenu);
            app.WriteVideoMenu.MenuSelectedFcn = createCallbackFcn(app, @WriteVideoMenuSelected, true);
            app.WriteVideoMenu.Text = 'WriteVideo';
            app.KeypointdetectMenu = uimenu(app.PreprocessMenu);
            app.KeypointdetectMenu.MenuSelectedFcn = createCallbackFcn(app, @KeypointdetectMenuSelected, true);
            app.KeypointdetectMenu.Text = 'Keypoint detect';
            app.ConvertmeshMenu = uimenu(app.PreprocessMenu);
            app.ConvertmeshMenu.MenuSelectedFcn = createCallbackFcn(app, @ConvertmeshMenuSelected, true);
            app.ConvertmeshMenu.Text = 'Convert mesh';
            app.EvaluateMenu = uimenu(app.Shape_analysisUIFigure);
            app.EvaluateMenu.Text = 'Evaluate';
            app.AddBCSgroundtruthMenu = uimenu(app.EvaluateMenu);
            app.AddBCSgroundtruthMenu.MenuSelectedFcn = createCallbackFcn(app, @AddBCSgroundtruthMenuSelected, true);
            app.AddBCSgroundtruthMenu.Text = 'Add BCS ground truth';
            app.EvaluateaccuracyMenu = uimenu(app.EvaluateMenu);
            app.EvaluateaccuracyMenu.MenuSelectedFcn = createCallbackFcn(app, @EvaluateaccuracyMenuSelected, true);
            app.EvaluateaccuracyMenu.Text = 'Evaluate accuracy';
            app.ModelMenu = uimenu(app.Shape_analysisUIFigure);
            app.ModelMenu.Text = 'Model';
            app.TrainMenu = uimenu(app.ModelMenu);
            app.TrainMenu.MenuSelectedFcn = createCallbackFcn(app, @TrainMenuSelected, true);
            app.TrainMenu.Text = 'Train';
            app.ScoreMenu = uimenu(app.ModelMenu);
            app.ScoreMenu.Text = 'Score';
            app.SingleMenu = uimenu(app.ScoreMenu);
            app.SingleMenu.MenuSelectedFcn = createCallbackFcn(app, @SingleMenuSelected, true);
            app.SingleMenu.Text = 'Single';
            app.MultiplyMenu = uimenu(app.ScoreMenu);
            app.MultiplyMenu.MenuSelectedFcn = createCallbackFcn(app, @MultiplyMenuSelected, true);
            app.MultiplyMenu.Text = 'Multiply';
            app.TabGroup = uitabgroup(app.Shape_analysisUIFigure);
            app.WritevideoTab = uitab(app.TabGroup);
            app.WritevideoTab.Title = 'Write video';
            app.PanelConvertdepthimagetograyandsyntheticavideowithextensionavi = uipanel(app.WritevideoTab);
            app.PanelConvertdepthimagetograyandsyntheticavideowithextensionavi.Title = 'Panel 1: Convert depth image to gray image and write a video';
            app.WriteaviButton = uibutton(app.PanelConvertdepthimagetograyandsyntheticavideowithextensionavi, 'push');
            app.WriteaviButton.ButtonPushedFcn = createCallbackFcn(app, @WriteaviButtonPushed, true);
            app.WriteaviButton.Text = 'Write avi';
            app.WritegrayimageCheckBox = uicheckbox(app.PanelConvertdepthimagetograyandsyntheticavideowithextensionavi);
            app.WritegrayimageCheckBox.Text = 'Write gray image';
            app.WritegrayimageCheckBox.Value = true;
            app.CheckwhethertowritegrayimagesLabel = uilabel(app.PanelConvertdepthimagetograyandsyntheticavideowithextensionavi);
            app.CheckwhethertowritegrayimagesLabel.Enable = 'off';
            app.CheckwhethertowritegrayimagesLabel.Text = '##Check whether to write gray images.';
            app.DetectionTab = uitab(app.TabGroup);
            app.DetectionTab.Title = 'Detection';
            app.Panel2KeypointdetectioninDLC = uipanel(app.DetectionTab);
            app.Panel2KeypointdetectioninDLC.Title = 'Panel 2: Keypoint detection in DLC';
            app.OpenDLCButton = uibutton(app.Panel2KeypointdetectioninDLC, 'push');
            app.OpenDLCButton.ButtonPushedFcn = createCallbackFcn(app, @OpenDLCButtonPushed, true);
            app.OpenDLCButton.Text = 'Open DLC';
            app.OpentheGUIofDeepLabCutDLCanddetectkeypointsonthatinterfaceLabel = uilabel(app.Panel2KeypointdetectioninDLC);
            app.OpentheGUIofDeepLabCutDLCanddetectkeypointsonthatinterfaceLabel.Enable = 'off';
            app.OpentheGUIofDeepLabCutDLCanddetectkeypointsonthatinterfaceLabel.Text = '##Open the GUI of DeepLabCut(DLC) and detect key points on that interface.';
            app.ConverttomeshTab = uitab(app.TabGroup);
            app.ConverttomeshTab.Title = 'Convert to mesh';
            app.Panel3Converttomeshwithsametopologyprogress = uipanel(app.ConverttomeshTab);
            app.Panel3Converttomeshwithsametopologyprogress.Title = 'Panel 3: Convert to mesh with same topology progress';
            app.CalibrationparametersLabel = uilabel(app.Panel3Converttomeshwithsametopologyprogress);
            app.CalibrationparametersLabel.Text = 'Calibration parameters';
            app.fyTextAreaLabel = uilabel(app.Panel3Converttomeshwithsametopologyprogress);
            app.fyTextAreaLabel.Text = 'fy';
            app.fyTextArea = uitextarea(app.Panel3Converttomeshwithsametopologyprogress);
            app.cxTextAreaLabel = uilabel(app.Panel3Converttomeshwithsametopologyprogress);
            app.cxTextAreaLabel.Text = 'cx';
            app.cxTextArea = uitextarea(app.Panel3Converttomeshwithsametopologyprogress);
            app.cyTextAreaLabel = uilabel(app.Panel3Converttomeshwithsametopologyprogress);
            app.cyTextAreaLabel.Text = 'cy';
            app.cyTextArea = uitextarea(app.Panel3Converttomeshwithsametopologyprogress);
            app.fxTextAreaLabel = uilabel(app.Panel3Converttomeshwithsametopologyprogress);
            app.fxTextAreaLabel.Text = 'fx';
            app.fxTextArea = uitextarea(app.Panel3Converttomeshwithsametopologyprogress);
            app.StartconvertButton = uibutton(app.Panel3Converttomeshwithsametopologyprogress, 'push');
            app.StartconvertButton.ButtonPushedFcn = createCallbackFcn(app, @StartconvertButtonPushed, true);
            app.StartconvertButton.Text = 'Start convert';
            app.SetparameterstoconvertdepthimagestomeshLabel = uilabel(app.Panel3Converttomeshwithsametopologyprogress);
            app.SetparameterstoconvertdepthimagestomeshLabel.Enable = 'off';
            app.SetparameterstoconvertdepthimagestomeshLabel.Text = '##Set parameters to convert depth images to mesh.';
            app.TrimmingmethodButtonGroup = uibuttongroup(app.Panel3Converttomeshwithsametopologyprogress);
            app.TrimmingmethodButtonGroup.Title = 'Trimming method';
            app.ConvexButton = uiradiobutton(app.TrimmingmethodButtonGroup);
            app.ConvexButton.Text = 'Convex';
            app.ConvexButton.Value = true;
            app.NonconvexButton = uiradiobutton(app.TrimmingmethodButtonGroup);
            app.NonconvexButton.Text = 'Nonconvex';
            app.IndicatorlightLampLabel = uilabel(app.Panel3Converttomeshwithsametopologyprogress);
            app.IndicatorlightLampLabel.Text = 'Indicator light ';
            app.IndicatorlightLamp = uilamp(app.Panel3Converttomeshwithsametopologyprogress);
            app.DownsamplelevelSpinnerLabel = uilabel(app.Panel3Converttomeshwithsametopologyprogress);
            app.DownsamplelevelSpinnerLabel.Text = 'Downsample level';
            app.DownsamplelevelSpinner = uispinner(app.Panel3Converttomeshwithsametopologyprogress);
            app.DownsamplelevelSpinner.Limits = [0 10];
            app.DownsamplelevelSpinner.Value = 2;
            app.SmoothlevelLabel = uilabel(app.Panel3Converttomeshwithsametopologyprogress);
            app.SmoothlevelLabel.Text = 'Smooth level    ';
            app.SmoothlevelSpinner = uispinner(app.Panel3Converttomeshwithsametopologyprogress);
            app.SmoothlevelSpinner.Limits = [0 10];
            app.SmoothlevelSpinner.Value = 1;
            app.WriteoriginalpointcloudCheckBox = uicheckbox(app.Panel3Converttomeshwithsametopologyprogress);
            app.WriteoriginalpointcloudCheckBox.Text = 'Write original pointcloud';
            app.AddNonrigidtransformCheckBox = uicheckbox(app.Panel3Converttomeshwithsametopologyprogress);
            app.AddNonrigidtransformCheckBox.Text = 'Add Non-rigid transform';
            app.InputtruthTab = uitab(app.TabGroup);
            app.InputtruthTab.Title = 'Input truth';
            app.Panel4Inputgroundtruthvalue = uipanel(app.InputtruthTab);
            app.Panel4Inputgroundtruthvalue.Title = 'Panel 4: Input ground truth value';
            app.UITable = uitable(app.Panel4Inputgroundtruthvalue);
            app.UITable.ColumnName = {'Image_Num'; 'BCS'; 'origin_filename'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [false true false];
            app.EnterthegroundtruthinthesecondcolumnLabel = uilabel(app.Panel4Inputgroundtruthvalue);
            app.EnterthegroundtruthinthesecondcolumnLabel.Enable = 'off';
            app.EnterthegroundtruthinthesecondcolumnLabel.Text = '##Enter the ground truth in the second column.';
            app.LoadButton = uibutton(app.Panel4Inputgroundtruthvalue, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.ImportButton = uibutton(app.Panel4Inputgroundtruthvalue, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportButton.Text = 'Import';
            app.SaveButton = uibutton(app.Panel4Inputgroundtruthvalue, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.EvaluateTab = uitab(app.TabGroup);
            app.EvaluateTab.Title = 'Evaluate';
            app.Panel5Evaluateaccuracy = uipanel(app.EvaluateTab);
            app.Panel5Evaluateaccuracy.Title = 'Panel 5: Evaluate accuracy';
            app.StartevaluateButton = uibutton(app.Panel5Evaluateaccuracy, 'push');
            app.StartevaluateButton.ButtonPushedFcn = createCallbackFcn(app, @StartevaluateButtonPushed, true);
            app.StartevaluateButton.Text = 'Start evaluate';
            app.IndicatorlightLamp_2Label = uilabel(app.Panel5Evaluateaccuracy);
            app.IndicatorlightLamp_2Label.Text = 'Indicator light ';
            app.IndicatorlightLamp_2 = uilamp(app.Panel5Evaluateaccuracy);
            app.TesttimesSpinner_2Label = uilabel(app.Panel5Evaluateaccuracy);
            app.TesttimesSpinner_2Label.Text = 'Test times';
            app.TesttimesSpinner = uispinner(app.Panel5Evaluateaccuracy);
            app.TesttimesSpinner.Limits = [1 Inf];
            app.TesttimesSpinner.RoundFractionalValues = 'on';
            app.TesttimesSpinner.Value = 1;
            app.DiscretizationincrementEditFieldLabel = uilabel(app.Panel5Evaluateaccuracy);
            app.DiscretizationincrementEditFieldLabel.Text = 'Discretization increment';
            app.DiscretizationincrementEditField = uieditfield(app.Panel5Evaluateaccuracy, 'numeric');
            app.DiscretizationincrementEditField.Limits = [0 Inf];
            app.DisplayincrementEditFieldLabel = uilabel(app.Panel5Evaluateaccuracy);
            app.DisplayincrementEditFieldLabel.Text = 'Display increment';
            app.DisplayincrementEditField = uieditfield(app.Panel5Evaluateaccuracy, 'numeric');
            app.DisplayincrementEditField.Limits = [0 Inf];
            app.LowerlimitofvalueEditFieldLabel = uilabel(app.Panel5Evaluateaccuracy);
            app.LowerlimitofvalueEditFieldLabel.Text = 'Lower limit of  value';
            app.LowerlimitofvalueEditField = uieditfield(app.Panel5Evaluateaccuracy, 'numeric');
            app.UpperlimitofvalueEditFieldLabel = uilabel(app.Panel5Evaluateaccuracy);
            app.UpperlimitofvalueEditFieldLabel.Text = 'Upper limit of  value';
            app.UpperlimitofvalueEditField = uieditfield(app.Panel5Evaluateaccuracy, 'numeric');
            app.Diff_cal_spaceDropDownLabel = uilabel(app.Panel5Evaluateaccuracy);
            app.Diff_cal_spaceDropDownLabel.Text = 'Diff_cal_space';
            app.Diff_cal_spaceDropDown = uidropdown(app.Panel5Evaluateaccuracy);
            app.Diff_cal_spaceDropDown.Items = {'euclidean', 'Basis vector'};
            app.Diff_cal_spaceDropDown.Value = 'euclidean';
            app.UITable2 = uitable(app.EvaluateTab);
            app.UITable2.ColumnName = {'Difference'; 'Mean'; 'Min'; 'Max'};
            app.UITable2.RowName = {};
            app.TextArea = uitextarea(app.EvaluateTab);
            app.TrainTab = uitab(app.TabGroup);
            app.TrainTab.Title = 'Train';
            app.Panel6Trainamodel = uipanel(app.TrainTab);
            app.Panel6Trainamodel.Title = 'Panel 6: Train a model';
            app.TrainButton = uibutton(app.Panel6Trainamodel, 'push');
            app.TrainButton.ButtonPushedFcn = createCallbackFcn(app, @TrainButtonPushed, true);
            app.TrainButton.Text = 'Train';
            app.TrainaaverageshapeandrecordtheminandmaxscoreLabel = uilabel(app.Panel6Trainamodel);
            app.TrainaaverageshapeandrecordtheminandmaxscoreLabel.Enable = 'off';
            app.TrainaaverageshapeandrecordtheminandmaxscoreLabel.Text = '##Train an average shape and record the minimum and maximum scores';
            app.WritethealignedmeshesCheckBox = uicheckbox(app.Panel6Trainamodel);
            app.WritethealignedmeshesCheckBox.Text = 'Write the aligned meshes';
            app.Difference_calculate_spaceDropDownLabel = uilabel(app.Panel6Trainamodel);
            app.Difference_calculate_spaceDropDownLabel.Text = 'Difference_calculate_space';
            app.Difference_calculate_spaceDropDown = uidropdown(app.Panel6Trainamodel);
            app.Difference_calculate_spaceDropDown.Items = {'euclidean', 'Basis vector'};
            app.Difference_calculate_spaceDropDown.Value = 'euclidean';
            app.ScoreTab = uitab(app.TabGroup);
            app.ScoreTab.Title = 'Score';
            app.Panel7Scoretheanimals = uipanel(app.ScoreTab);
            app.Panel7Scoretheanimals.Title = 'Panel 7: Score the animals';
            app.EditparaButton = uibutton(app.Panel7Scoretheanimals, 'push');
            app.EditparaButton.ButtonPushedFcn = createCallbackFcn(app, @EditparaButtonPushed, true);
            app.EditparaButton.Text = 'Edit para';
            app.ScoreButton = uibutton(app.Panel7Scoretheanimals, 'push');
            app.ScoreButton.ButtonPushedFcn = createCallbackFcn(app, @ScoreButtonPushed, true);
            app.ScoreButton.Text = 'Score';
            app.FileordirectoryButtonGroup = uibuttongroup(app.Panel7Scoretheanimals);
            app.FileordirectoryButtonGroup.Title = 'File or directory';
            app.SingleButton = uiradiobutton(app.FileordirectoryButtonGroup);
            app.SingleButton.Text = 'Single';
            app.SingleButton.Value = true;
            app.MultipleButton = uiradiobutton(app.FileordirectoryButtonGroup);
            app.MultipleButton.Text = 'Multiple';
            app.UITable3 = uitable(app.Panel7Scoretheanimals);
            app.UITable3.ColumnName = {'Filename'; 'BCS'};
            app.UITable3.RowName = {};
            app.ExportButton = uibutton(app.Panel7Scoretheanimals, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.DLCpath = uieditfield(app.Panel7Scoretheanimals, 'text');
            app.DLCpath.Editable = 'off';
            app.DLCpath.Placeholder = 'DLC path';
            app.BrowseButton = uibutton(app.Panel7Scoretheanimals, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.InfoOutTextArea = uitextarea(app.Shape_analysisUIFigure);
            app.InfoOutTextArea.Editable = 'off';
            app.InfoOutTextArea.Placeholder = 'Output prompt here';
            % Show the figure after all components are created
            app.Shape_analysisUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Shape_analysis(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Shape_analysisUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Shape_analysisUIFigure)
        end
    end
end