classdef Shape_analysis_mul < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Shape_analysis_mulFigure        matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        ReturnMainMenu                  matlab.ui.container.Menu
        ExitMenu                        matlab.ui.container.Menu
        TabGroup                        matlab.ui.container.TabGroup
        TrainTab                        matlab.ui.container.Tab
        Panel1TrainaFNNmodel            matlab.ui.container.Panel
        TrainButton                     matlab.ui.control.Button
        SaveNetFilenameEditField        matlab.ui.control.EditField
        SaveNetFilenameEditFieldLabel   matlab.ui.control.Label
        ImportinputandtargetPanel       matlab.ui.container.Panel
        TargetEditField                 matlab.ui.control.EditField
        TargetEditFieldLabel            matlab.ui.control.Label
        InputEditField                  matlab.ui.control.EditField
        InputEditFieldLabel             matlab.ui.control.Label
        ImportYButton                   matlab.ui.control.Button
        ImportXButton                   matlab.ui.control.Button
        TrainparameterPanel             matlab.ui.container.Panel
        LearningRateEditField           matlab.ui.control.NumericEditField
        LearningRateEditFieldLabel      matlab.ui.control.Label
        MaxTrainingEpochsSpinner        matlab.ui.control.Spinner
        MaxTrainingEpochsSpinnerLabel   matlab.ui.control.Label
        hiddenlayerneuronsSpinner       matlab.ui.control.Spinner
        hiddenlayerneuronsSpinnerLabel  matlab.ui.control.Label
        EvaluateTab                     matlab.ui.container.Tab
        Panel2Evaluateaccuracy          matlab.ui.container.Panel
        Diff_cal_spaceDropDown          matlab.ui.control.DropDown
        Diff_cal_spaceDropDownLabel     matlab.ui.control.Label
        ImportnetButton                 matlab.ui.control.Button
        SelectnetEditField              matlab.ui.control.EditField
        SelectnetEditFieldLabel         matlab.ui.control.Label
        UpperlimitofvalueEditField      matlab.ui.control.NumericEditField
        UpperlimitofvalueEditFieldLabel  matlab.ui.control.Label
        LowerlimitofvalueEditField      matlab.ui.control.NumericEditField
        LowerlimitofvalueEditFieldLabel  matlab.ui.control.Label
        DisplayincrementEditField       matlab.ui.control.NumericEditField
        DisplayincrementEditFieldLabel  matlab.ui.control.Label
        TextArea_2                      matlab.ui.control.TextArea
        TextArea                        matlab.ui.control.TextArea
        UITable2                        matlab.ui.control.Table
        DiscretizationincrementEditField  matlab.ui.control.NumericEditField
        DiscretizationincrementEditFieldLabel  matlab.ui.control.Label
        TesttimesSpinner                matlab.ui.control.Spinner
        TesttimesSpinner_2Label         matlab.ui.control.Label
        IndicatorlightLamp_2            matlab.ui.control.Lamp
        IndicatorlightLamp_2Label       matlab.ui.control.Label
        StartevaluateButton             matlab.ui.control.Button
    end


    properties (Access = private)
        project_name;
        option;
    end

    methods (Access = private)

        function Evaluate_Accuracy(app)
            %% Evaluate accuracy through multiple tests
            if isempty(app.SelectnetEditField.Value)
                errordlg("Please import the net firstly");
                return;
            end
            % Pre-processing data(Train)
            fullpath=mfilename("fullpath");
            [Mainpath,~]=fileparts(fullpath);
            Mainpath=string(Mainpath);
            for i=1:numel(app.option)
                if ~exist(strcat(Mainpath,"\",app.option{i},"\Preprocessed\mesh\"),'file')||...
                        isempty(dir(strcat(Mainpath,"\",app.option{i},"\Preprocessed\mesh\*.ply")))
                    errordlg([app.option{i},' mesh data does not exist, please reconstruct the mesh firstly'],'Not file error');
                    return;
                end
            end

            % Indicator light turns green
            app.IndicatorlightLamp_2.Color=[0,1,0];
            path=string(zeros(numel(app.option),1));
            for i=1:numel(app.option)
                path(i)=strcat(Mainpath,"\",app.option{i},"\Preprocessed\mesh\");
                file=dir(strcat(path(i),"*.ply"));
                if i==1
                    filename=arrayfun(@(x)x.name,file,'UniformOutput',false);
                else
                    filename=intersect(filename,arrayfun(@(x)x.name,file,'UniformOutput',false));
                end
            end
            filename=string(filename);
            for i=1:length(filename)
                if isempty(find(strcmp(filename,file(i).name),1))
                    file(i)=[];
                end
            end

            N=length(filename);
            file_idx=1:N;
            train_num=round(N*0.8); % The size of training set
            test_num=N-train_num; % The size of test set
            str=sprintf('Training Set Size: %d\nTest Set Size: %d',train_num,test_num);
            app.TextArea.Value=str;
            pause(1e-6);

            % Read the BCS
            BCS_file=readtable(strcat(Mainpath,"\",app.option{1},"\BCS.dat"));
            BCS_fn=string(BCS_file{:,1});
            BCS=table2array(BCS_file(:,2));
            BCS_train_idx=zeros(train_num,1);

            test_times=app.TesttimesSpinner.Value;
            acc=zeros(test_times,1);
            app_acc=zeros(test_times,4);
            Bar=waitbar(0,'Evaluating accuracy');
            for r=1:test_times
                % Determine if the user has interrupted the program
                if ~isvalid(Bar)
                    app.IndicatorlightLamp_2.Color=[1,0,0];
                    errordlg('User stop','User Stop');
                    return;
                end

                waitbar(r/test_times,Bar,'Evaluating accuracy'); % Update progress bar

                % Obtain the training set randomly from whole set
                train_idx=sort(file_idx(randperm(numel(file_idx),train_num)));

                % Obtain the BCS corresponding to the training set
                for i=1:train_num
                    train_name=split(file(train_idx(i)).name,["_","."]);
                    train_name=string(str2double(train_name(2)));
                    BCS_train_idx(i)=find(strcmp(train_name,BCS_fn)==1);
                end
                BCS_train=BCS(BCS_train_idx);

                %Set the remaining dataset as the test set
                test_idx=setdiff(file_idx,train_idx);
                norm_score=zeros(test_num,numel(app.option));
                for k=1:numel(app.option)
                    train_mesh=readSurfaceMesh(strcat(path(k),string(file(train_idx(1)).name))); %average shape variable
                    V_0=train_mesh.Vertices;
                    T_0=train_mesh.Faces;
                    V_0=V_0-mean(V_0);%Translate the target point's center of gravity to the origin
                    V_0=V_0/sqrt(sum(sum(V_0.^2)));%normalization

                    Vnum=size(V_0,1);
                    V=cell(train_num,1);

                    %Translate the source points' center of gravity to the origin and normalization it
                    for i=1:train_num
                        V{i}=readSurfaceMesh(strcat(path(k),string(file(train_idx(i)).name))).Vertices;
                        V{i}=V{i}-mean(V{i});
                        V{i}=V{i}/sqrt(sum(sum(V{i}.^2)));
                    end

                    % Alignment
                    iter=0;
                    while iter<10
                        iter=iter+1;

                        % Align all shapes to the average shape
                        for i=1:train_num
                            V_i=V{i};
                            [~,TV_i,~]=procrustes(V_0,V_i);
                            V{i}=TV_i;
                        end

                        V_0_temp=generate_avg_shape(V,train_num,Vnum);
                        [~,V_0_temp,~]=procrustes(V_0,V_0_temp);
                        V_0=V_0_temp;
                    end

                    % Align the boundaries after the shape is basically aligned
                    tri_0=triangulation(double(T_0),double(V_0));
                    b_0=tri_0.freeBoundary;
                    b_0=b_0(:,1);
                    p_0=pointCloud(V_0(b_0,:));
                    for i=1:train_num
                        tri_i=triangulation(double(T_0),double(V{i}));
                        b_i=tri_i.freeBoundary;
                        b_i=b_i(:,1);
                        p_i=pointCloud(V{i}(b_i,:));
                        t=pcregistericp(p_i,p_0);
                        V{i}=V{i}*t.R+t.Translation;
                    end

                    if strcmp(app.Diff_cal_spaceDropDown.Value,'Basis vector')
                        %% Obtain the basis vector of the shape
                        Stacked_V=zeros(size(V{1},1)*size(V{1},2),length(V));
                        for i=1:length(V)
                            Stacked_V(:,i)=reshape(V{i}',[],1);
                        end
                        Stacked_V=Stacked_V';
                        centered_X = Stacked_V - mean(Stacked_V);
                        covariance = cov(centered_X);
                        [Vector, D] = eig(covariance);
                        [sorted_eigenvalues, index] = sort(diag(D), 'descend');
                        total_variance = sum(sorted_eigenvalues);
                        target_variance = 0.99 * total_variance;
                        explained_variance = 0;
                        i = 1;
                        while explained_variance < target_variance
                            explained_variance = explained_variance + sorted_eigenvalues(i);
                            i = i + 1;
                        end
                        basis_vectors = Vector(:, index(1:i-1));
                    else
                        %% Adjust the position of the livestocks' back
                        mu=mean(V_0);
                        rotate_mat=pca(V_0-mu); % Find the main direction of the point set
                        rotate_x=[1 0 0;0 -1 0;0 0 -1]; % Flip it 180 degrees along the x-axis.
                        V_0=(V_0-mu)*rotate_mat*rotate_x;
                        for i=1:train_num
                            V{i}=(V{i}-mu)*rotate_mat*rotate_x;
                        end
                    end
                    
                    min_BCS=min(BCS_train);
                    max_BCS=max(BCS_train);
                    V_model=V_0;

                    if strcmp(app.Diff_cal_spaceDropDown.Value,'Basis vector')
                        V_model_in_basis=basis_vectors\reshape(V_model',[],1);
                    end

                    % Calculate scores of animals in training sets
                    train_score=zeros(train_num,1);
                    for i=1:train_num
                        V_S=V{i};
                        if strcmp(app.Diff_cal_spaceDropDown.Value,'Basis vector')
                            V_S_in_basis=basis_vectors\reshape(V_S',[],1);
                            train_score(i)=evaluate_score(V_S_in_basis,V_model_in_basis,T_0,'basis_vector');
                        else
                            train_score(i)=evaluate_score(V_S,V_model,T_0,'euclidean');
                        end
                    end
                    min_score=min(train_score);
                    max_score=max(train_score);


                    %% Evaluate scores of all animals(Test)
                    % Obtain the BCS corresponding to the test set
                    BCS_test_idx=zeros(test_num,1);
                    for i=1:test_num
                        test_name=split(file(test_idx(i)).name,["_","."]);
                        test_name=string(str2double(test_name(2)));
                        BCS_test_idx(i)=find(strcmp(test_name,BCS_fn)==1);
                    end
                    BCS_test=BCS(BCS_test_idx);

                    meshname=strings(length(test_num),1);
                    for n=1:test_num
                        temp=split(file(test_idx(n)).name,["_","."]);
                        meshname(n)=temp(2);
                    end
                    meshname=str2double(meshname);

                    V_T=V_model;
                    Faces=T_0;
                    test_score=zeros(test_num,1);

                    % Calculate scores of all livestocks
                    for i=1:test_num
                        V_S=readSurfaceMesh(strcat(path(k),string(file(test_idx(i)).name))).Vertices;
                        V_S=V_S-mean(V_S);
                        V_S=V_S/sqrt(sum(sum(V_S.^2)));

                        [~,V_S,~]=procrustes(V_T,V_S);
                        if strcmp(app.Diff_cal_spaceDropDown.Value,'Basis vector')
                            V_S_in_basis=basis_vectors\reshape(V_S',[],1);
                            test_score(i)=evaluate_score(V_S_in_basis,V_model_in_basis,Faces,'basis_vector');
                        else
                            test_score(i)=evaluate_score(V_S,V_T,Faces,'euclidean');
                        end
                    end

                    % Standardized BCS
                    up=app.UpperlimitofvalueEditField.Value;
                    low=app.LowerlimitofvalueEditField.Value;
                    if up<low
                        errordlg("The lower limit must be less than the upper",'limit error');
                        return;
                    end
                    if app.DiscretizationincrementEditField.Value~=0
                        enum=low:app.DiscretizationincrementEditField.Value:up;
                    end
                    norm_score(:,k)=(test_score-min_score)/(max_score-min_score)...
                        *(max_BCS-min_BCS)+min_BCS;
                end
                
                load net_FNN.mat net;
                Y_pred=net(norm_score');
                norm_score=Y_pred';
                
                %% calculate the accuracy
                acc_num=0;
                approximate_num=zeros(1,4);
                file_name=split(BCS_fn,["_","."]);
                file_name=str2double(file_name);
                idx=zeros(test_num,1);
                for n=1:test_num
                    idx(n)=find(file_name==meshname(n));
                end

                for i=1:test_num
                    % Discretizing BCS
                    if app.DiscretizationincrementEditField.Value~=0
                        neigh=abs(enum-norm_score(i));
                        order=neigh==min(neigh);
                        norm_score(i)=enum(order);
                    end

                    name=split(file(test_idx(i)).name,["_","."]);
                    name=name{2};
                    j=find(file_name(idx)==str2double(name));

                    if abs(norm_score(i)-BCS_test(j))<=0
                        acc_num=acc_num+1;
                    end

                    dis_incre=app.DisplayincrementEditField.Value;
                    for k=1:4
                        if abs(norm_score(i)-BCS_test(j))<=(dis_incre*k)
                            approximate_num(k)=approximate_num(k)+1;
                        end
                    end
                end
                
                acc(r)=acc_num/test_num;
                app_acc(r,:)=approximate_num/test_num;
            end
            Avg=[mean(acc)*100;mean(app_acc(:,1))*100;mean(app_acc(:,2))*100;...
                mean(app_acc(:,3))*100;mean(app_acc(:,4))*100];
            Min=[min(acc)*100;min(app_acc(:,1))*100;min(app_acc(:,2))*100;...
                min(app_acc(:,3))*100;min(app_acc(:,4))*100];
            Max=[max(acc)*100;max(app_acc(:,1))*100;max(app_acc(:,2))*100;...
                max(app_acc(:,3))*100;max(app_acc(:,4))*100];
            diff1=sprintf("Diff==%f",dis_incre*0);
            diff2=sprintf("Diff<=%f",dis_incre*1);
            diff3=sprintf("Diff<=%f",dis_incre*2);
            diff4=sprintf("Diff<=%f",dis_incre*3);
            diff5=sprintf("Diff<=%f",dis_incre*4);
            Differ=[diff1;diff2;diff3;diff4;diff5];
            close(Bar);
            Table=table(Differ,Avg,Min,Max);
            app.UITable2.Data=Table;
            app.IndicatorlightLamp_2.Color=[1,0,0];
        end

        function my_closereq(~,fig)
            close_selection = uiconfirm(fig,'Close the figure window?',...
                'Confirmation');
            switch close_selection
                case 'OK'
                    delete(fig);
                case 'Cancel'
                    return
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, option)
            app.DiscretizationincrementEditField.Value=0.25;
            app.DisplayincrementEditField.Value=0.25;
            app.UpperlimitofvalueEditField.Value=9;
            app.LowerlimitofvalueEditField.Value=0;
            movegui(app.Shape_analysis_mulFigure,'center');
            app.Shape_analysis_mulFigure.CloseRequestFcn=@(src,event)my_closereq(app,src);
            app.Shape_analysis_mulFigure.Name='Shape_analysis_mul';
            app.option=option;
            app.TextArea_2.Value=string(option);
        end

        % Menu selected function: ExitMenu
        function ExitMenuSelected(app, event)
            close_selection=uiconfirm(app.Shape_analysis_mulFigure,'Close software?','Quit','Icon','warning');
            switch close_selection
                case 'OK'
                    app.delete;
                case 'Cancel'
                    return
            end
        end

        % Button pushed function: StartevaluateButton
        function StartevaluateButtonPushed(app, event)
            Evaluate_Accuracy(app);
        end

        % Menu selected function: ReturnMainMenu
        function ReturnMainMenuSelected(app, event)
            Main;
            delete(app);
        end

        % Button pushed function: ImportXButton
        function ImportXButtonPushed(app, event)
            [filename,path]=uigetfile({'*.mat','Files(*.mat)'});
             if isequal(filename,0)
                Output_Info(app,'User selected Cancel');
                return;
            end
            app.InputEditField.Value=fullfile(path,filename);
        end

        % Button pushed function: ImportYButton
        function ImportYButtonPushed(app, event)
            [filename,path]=uigetfile({'*.mat','Files(*.mat)'});
             if isequal(filename,0)
                Output_Info(app,'User selected Cancel');
                return;
            end
            app.TargetEditField.Value=fullfile(path,filename);
        end

        % Button pushed function: TrainButton
        function TrainButtonPushed(app, event)
            str1=load(app.InputEditField.Value);
            val_names=fieldnames(str1);
            X=getfield(str1,val_names{1});
            str2=load(app.TargetEditField.Value);
            val_names=fieldnames(str2);
            Y=getfield(str2,val_names{1});
            if max(size(X,1),size(X,2))<=3
                errordlg("Insufficient data volume",'Insufficient data volume');
                return;
            end
            if size(X,1)>size(X,2)
                X=X';
            end
            if size(Y,1)>size(Y,2)
                Y=Y';
            end
            if size(X,2)~=size(Y,2)
                errordlg("The number of inputs and targets does not match");
                return;
            end
            %% FFNN
            net = feedforwardnet(app.hiddenlayerneuronsSpinner.Value);

            net.trainParam.epochs = app.MaxTrainingEpochsSpinner.Value; 
            net.trainParam.goal = 1e-5; 
            net.trainParam.lr = app.LearningRateEditField.Value; 

            net.divideParam.trainRatio = 1;
            net.divideParam.valRatio = 0;
            net.divideParam.testRatio = 0;

            net = train(net, X,Y);
            save(app.SaveNetFilenameEditField.Value,'net');
        end

        % Button pushed function: ImportnetButton
        function ImportnetButtonPushed(app, event)
            [filename,path]=uigetfile({'*.mat','Files(*.mat)'});
             if isequal(filename,0)
                Output_Info(app,'User selected Cancel');
                return;
            end
            app.SelectnetEditField.Value=fullfile(path,filename);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.Shape_analysis_mulFigure = uifigure('Visible', 'off');
            app.Shape_analysis_mulFigure.Name = 'Shape_analysis_mul';
            app.Shape_analysis_mulFigure.Icon = fullfile(pathToMLAPP, 'Icon', 'Logo.png');
            app.FileMenu = uimenu(app.Shape_analysis_mulFigure);
            app.FileMenu.Text = 'File';
            app.ReturnMainMenu = uimenu(app.FileMenu);
            app.ReturnMainMenu.MenuSelectedFcn = createCallbackFcn(app, @ReturnMainMenuSelected, true);
            app.ReturnMainMenu.Text = 'Return Main';
            app.ExitMenu = uimenu(app.FileMenu);
            app.ExitMenu.MenuSelectedFcn = createCallbackFcn(app, @ExitMenuSelected, true);
            app.ExitMenu.Text = 'Exit';
            app.TabGroup = uitabgroup(app.Shape_analysis_mulFigure);
            app.TrainTab = uitab(app.TabGroup);
            app.TrainTab.Title = 'Train';
            app.Panel1TrainaFNNmodel = uipanel(app.TrainTab);
            app.Panel1TrainaFNNmodel.Title = 'Panel 1: Train a FNN model';
            app.TrainparameterPanel = uipanel(app.Panel1TrainaFNNmodel);
            app.TrainparameterPanel.Title = 'Train parameter';
            app.hiddenlayerneuronsSpinnerLabel = uilabel(app.TrainparameterPanel);
            app.hiddenlayerneuronsSpinnerLabel.Text = 'hidden layer neurons';
            app.hiddenlayerneuronsSpinner = uispinner(app.TrainparameterPanel);
            app.hiddenlayerneuronsSpinner.Limits = [1 Inf];
            app.hiddenlayerneuronsSpinner.Value = 3;
            app.MaxTrainingEpochsSpinnerLabel = uilabel(app.TrainparameterPanel);
            app.MaxTrainingEpochsSpinnerLabel.Text = 'Max Training Epochs';
            app.MaxTrainingEpochsSpinner = uispinner(app.TrainparameterPanel);
            app.MaxTrainingEpochsSpinner.Limits = [1 Inf];
            app.MaxTrainingEpochsSpinner.Value = 30000;
            app.LearningRateEditFieldLabel = uilabel(app.TrainparameterPanel);
            app.LearningRateEditFieldLabel.Text = 'Learning Rate';
            app.LearningRateEditField = uieditfield(app.TrainparameterPanel, 'numeric');
            app.LearningRateEditField.Limits = [0 1];
            app.LearningRateEditField.Value = 1e-05;
            app.ImportinputandtargetPanel = uipanel(app.Panel1TrainaFNNmodel);
            app.ImportinputandtargetPanel.Title = 'Import input and target';
            app.ImportXButton = uibutton(app.ImportinputandtargetPanel, 'push');
            app.ImportXButton.ButtonPushedFcn = createCallbackFcn(app, @ImportXButtonPushed, true);
            app.ImportXButton.Text = 'Import X';
            app.ImportYButton = uibutton(app.ImportinputandtargetPanel, 'push');
            app.ImportYButton.ButtonPushedFcn = createCallbackFcn(app, @ImportYButtonPushed, true);
            app.ImportYButton.Text = 'Import Y';
            app.InputEditFieldLabel = uilabel(app.ImportinputandtargetPanel);
            app.InputEditFieldLabel.Text = 'Input';
            app.InputEditField = uieditfield(app.ImportinputandtargetPanel, 'text');
            app.InputEditField.Editable = 'off';
            app.InputEditField.Placeholder = 'Path of input data';
            app.TargetEditFieldLabel = uilabel(app.ImportinputandtargetPanel);
            app.TargetEditFieldLabel.Text = 'Target';
            app.TargetEditField = uieditfield(app.ImportinputandtargetPanel, 'text');
            app.TargetEditField.Editable = 'off';
            app.TargetEditField.Placeholder = 'Path of target data';
            app.SaveNetFilenameEditFieldLabel = uilabel(app.Panel1TrainaFNNmodel);
            app.SaveNetFilenameEditFieldLabel.Text = 'Save Net Filename';
            app.SaveNetFilenameEditField = uieditfield(app.Panel1TrainaFNNmodel, 'text');
            app.SaveNetFilenameEditField.Value = 'net_FNN';
            app.TrainButton = uibutton(app.Panel1TrainaFNNmodel, 'push');
            app.TrainButton.ButtonPushedFcn = createCallbackFcn(app, @TrainButtonPushed, true);
            app.TrainButton.Text = 'Train';
            app.EvaluateTab = uitab(app.TabGroup);
            app.EvaluateTab.Title = 'Evaluate';
            app.Panel2Evaluateaccuracy = uipanel(app.EvaluateTab);
            app.Panel2Evaluateaccuracy.Title = 'Panel 2: Evaluate accuracy';
            app.StartevaluateButton = uibutton(app.Panel2Evaluateaccuracy, 'push');
            app.StartevaluateButton.ButtonPushedFcn = createCallbackFcn(app, @StartevaluateButtonPushed, true);
            app.StartevaluateButton.Text = 'Start evaluate';
            app.IndicatorlightLamp_2Label = uilabel(app.Panel2Evaluateaccuracy);
            app.IndicatorlightLamp_2Label.Text = 'Indicator light ';
            app.IndicatorlightLamp_2 = uilamp(app.Panel2Evaluateaccuracy);
            app.TesttimesSpinner_2Label = uilabel(app.Panel2Evaluateaccuracy);
            app.TesttimesSpinner_2Label.Text = 'Test times';
            app.TesttimesSpinner = uispinner(app.Panel2Evaluateaccuracy);
            app.TesttimesSpinner.Limits = [1 Inf];
            app.TesttimesSpinner.RoundFractionalValues = 'on';
            app.TesttimesSpinner.Value = 1;
            app.DiscretizationincrementEditFieldLabel = uilabel(app.Panel2Evaluateaccuracy);
            app.DiscretizationincrementEditFieldLabel.Text = 'Discretization increment';
            app.DiscretizationincrementEditField = uieditfield(app.Panel2Evaluateaccuracy, 'numeric');
            app.DiscretizationincrementEditField.Limits = [0 Inf];
            app.UITable2 = uitable(app.Panel2Evaluateaccuracy);
            app.UITable2.ColumnName = {'Difference'; 'Mean'; 'Min'; 'Max'};
            app.UITable2.RowName = {};
            app.TextArea = uitextarea(app.Panel2Evaluateaccuracy);
            app.TextArea_2 = uitextarea(app.Panel2Evaluateaccuracy);
            app.TextArea_2.Editable = 'off';
            app.DisplayincrementEditFieldLabel = uilabel(app.Panel2Evaluateaccuracy);
            app.DisplayincrementEditFieldLabel.Text = 'Display increment';
            app.DisplayincrementEditField = uieditfield(app.Panel2Evaluateaccuracy, 'numeric');
            app.DisplayincrementEditField.Limits = [0 Inf];
            app.LowerlimitofvalueEditFieldLabel = uilabel(app.Panel2Evaluateaccuracy);
            app.LowerlimitofvalueEditFieldLabel.Text = 'Lower limit of  value';
            app.LowerlimitofvalueEditField = uieditfield(app.Panel2Evaluateaccuracy, 'numeric');
            app.LowerlimitofvalueEditField.Limits = [0 Inf];
            app.UpperlimitofvalueEditFieldLabel = uilabel(app.Panel2Evaluateaccuracy);
            app.UpperlimitofvalueEditFieldLabel.Text = 'Upper limit of  value';
            app.UpperlimitofvalueEditField = uieditfield(app.Panel2Evaluateaccuracy, 'numeric');
            app.UpperlimitofvalueEditField.Limits = [0 Inf];
            app.SelectnetEditFieldLabel = uilabel(app.Panel2Evaluateaccuracy);
            app.SelectnetEditFieldLabel.Text = 'Select net';
            app.SelectnetEditField = uieditfield(app.Panel2Evaluateaccuracy, 'text');
            app.SelectnetEditField.Editable = 'off';
            app.SelectnetEditField.Placeholder = 'Path of net';
            app.ImportnetButton = uibutton(app.Panel2Evaluateaccuracy, 'push');
            app.ImportnetButton.ButtonPushedFcn = createCallbackFcn(app, @ImportnetButtonPushed, true);
            app.ImportnetButton.Text = 'Import net';
            app.Diff_cal_spaceDropDownLabel = uilabel(app.Panel2Evaluateaccuracy);
            app.Diff_cal_spaceDropDownLabel.Text = 'Diff_cal_space';
            app.Diff_cal_spaceDropDown = uidropdown(app.Panel2Evaluateaccuracy);
            app.Diff_cal_spaceDropDown.Items = {'Euclidean', 'Basis vector'};
            app.Diff_cal_spaceDropDown.Value = 'Euclidean';
            % Show the figure after all components are created
            app.Shape_analysis_mulFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Shape_analysis_mul(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Shape_analysis_mulFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Shape_analysis_mulFigure)
        end
    end
end